classdef MCMAHONAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Dynamic_slider               matlab.ui.control.Slider
        Dynamic_slider_label         matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        SAVENOTESButton              matlab.ui.control.Button
        NOTESTextArea                matlab.ui.control.TextArea
        NOTESTextAreaLabel           matlab.ui.control.Label
        SETUPPanel                   matlab.ui.container.Panel
        STEP2RUNPanel                matlab.ui.container.Panel
        Step2_run_button             matlab.ui.control.Button
        WASSRfield                   matlab.ui.control.NumericEditField
        WASSRLabel                   matlab.ui.control.Label
        post_injection_field         matlab.ui.control.NumericEditField
        post_injection_label         matlab.ui.control.Label
        Pre_injection_field          matlab.ui.control.NumericEditField
        Pre_injection_label          matlab.ui.control.Label
        medianfilterCheckBox         matlab.ui.control.CheckBox
        InitiallizingPanel           matlab.ui.container.Panel
        OpenButton                   matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
        ClearAllButton               matlab.ui.control.Button
        SAVEButton                   matlab.ui.control.Button
        LOADButton                   matlab.ui.control.Button
        FolderPathEditField          matlab.ui.control.EditField
        FolderPathEditFieldLabel     matlab.ui.control.Label
        COSTUMIZEPanel               matlab.ui.container.Panel
        movingAveragesPanel          matlab.ui.container.Panel
        LoanPeriodYearsEditField_10  matlab.ui.control.NumericEditField
        LoanPeriodYearsEditFieldLabel_5  matlab.ui.control.Label
        LoanPeriodYearsEditField_8   matlab.ui.control.NumericEditField
        ApplyFilterButton            matlab.ui.control.Button
        LoanPeriodYearsEditFieldLabel_4  matlab.ui.control.Label
        CostumizewithfilteringPanel  matlab.ui.container.Panel
        Slice_slider                 matlab.ui.control.Slider
        Slice_slider_Label           matlab.ui.control.Label
        RsquaredthresholdingPanel    matlab.ui.container.Panel
        Hyperlink                    matlab.ui.control.Hyperlink
        LoanPeriodYearsEditField_12  matlab.ui.control.NumericEditField
        ApplyThresholdButton         matlab.ui.control.Button
        LoanPeriodYearsEditFieldLabel_6  matlab.ui.control.Label
        DefineROIsPanel              matlab.ui.container.Panel
        ShapesButtonGroup            matlab.ui.container.ButtonGroup
        FreeGrowingButton            matlab.ui.control.RadioButton
        ElipseButton                 matlab.ui.control.RadioButton
        PolyButton                   matlab.ui.control.RadioButton
        FreeButton                   matlab.ui.control.RadioButton
        RUNROIsButton                matlab.ui.control.Button
        LoanPeriodYearsEditField_13  matlab.ui.control.NumericEditField
        LoanPeriodYearsEditFieldLabel_7  matlab.ui.control.Label
        LOADROIsButton               matlab.ui.control.Button
        ButtonGroup                  matlab.ui.container.ButtonGroup
        MTRRatioLabel                matlab.ui.control.Label
        inverseButton                matlab.ui.control.RadioButton
        CallibrationCurveCoefficientsPanel  matlab.ui.container.Panel
        p4EditField                  matlab.ui.control.NumericEditField
        p4EditFieldLabel             matlab.ui.control.Label
        p2EditField                  matlab.ui.control.NumericEditField
        p2EditFieldLabel             matlab.ui.control.Label
        p3EditField                  matlab.ui.control.NumericEditField
        p3EditFieldLabel             matlab.ui.control.Label
        p1EditField                  matlab.ui.control.NumericEditField
        p1EditFieldLabel             matlab.ui.control.Label
        pHmapButton                  matlab.ui.control.RadioButton
        pHHistogramButton            matlab.ui.control.RadioButton
        MTRratioButton               matlab.ui.control.RadioButton
        PixelAnalyzerPanel           matlab.ui.container.Panel
        M0onCheckBox                 matlab.ui.control.CheckBox
        PixeloverlayCheckBox         matlab.ui.control.CheckBox
        NormalizeCheckBox            matlab.ui.control.CheckBox
        Dynamic_axis                 matlab.ui.control.UIAxes
        VIEWPanel                    matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        IMAGETab                     matlab.ui.container.Tab
        Dynamics_axes                matlab.ui.control.UIAxes
        ROIMAPTab                    matlab.ui.container.Tab
        ROI_map                      matlab.ui.control.UIAxes
        R2Tab                        matlab.ui.container.Tab
        R2_map                       matlab.ui.control.UIAxes
        HistogramEQTab               matlab.ui.container.Tab
        Histogram_eq                 matlab.ui.control.UIAxes
        CostumizeView                matlab.ui.container.Panel
        NormalizationDropDown        matlab.ui.control.DropDown
        NormalizationDropDownLabel   matlab.ui.control.Label
        StackDropDown                matlab.ui.control.DropDown
        StackDropDownLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        
% 
%             runningApp = getRunningApp(app);
% 
%             % Check for running singleton app
%             if isempty(runningApp)
% 
%                 % Create UIFigure and components
%                 createComponents(app)
% 
%                 % Register the app with App Designer
%                 registerApp(app, app.figure1)
% 
%                 % Execute the startup function
%                 runStartupFcn(app, @(app)ratioCEST_gui_OpeningFcn(app, varargin{:}))
%             else
% 
%                 % Focus the running singleton app
%                 figure(runningApp.figure1)
% 
%                 app = runningApp;
%             end
% 
%             if nargout == 0
%                 clear app
%             end
%         
% 
%        
    

        end

        % Callback function: FolderPathEditField, post_injection_field
        function FolderPathEditFieldValueChanging(app, event)
           
            
        end

        % Callback function
        function OpenButtonPushed(app, event)

            foldername = uigetdir();
            app.FolderPathEditField.value = foldername;


            %              % --- Executes just before ratioCEST_gui is made visible.
%             
%             % Ensure that the app appears on screen when run
%             movegui(app.Image, 'onscreen');
%             
%             % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, app] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
%             
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % app    structure with app and user data (see GUIDATA)
%             % varargin   command line arguments to ratioCEST_gui (see VARARGIN)
%             
%             % Choose default command line output for ratioCEST_gui
%             app.output = hObject;
%             
%             
%             % exp_path
%             %set(app.exp_path, 'string', 'C:/Users/Stabinska/Documents/MRData/Bruker 500MHz/mslice_cestRARE_test/20210416_110320_mslice_cestRARE_test_20210416_Iopamidol_mic_1_7')
%             set(app.FolderPathEditField)
%             
%             % pre_exp_no
             set(app.Pre_injection_field.value, 'string', '');
%             
%             % post_exp_no
            set(app.Post_injection_fieldl.value, 'string', '');
%             
%             % post_exp_no
            set(app.WASSRfield.value, 'string', '');
%             
%             % filter_checkbox
            set(app.medianfilterCheckox.value, 'string', '');
%             
%             % NormalizationDropDown menu 
            set(app.NormalizationDropDown);
%             
%         
%             % Stack drop down menu
%             set(app.StackDropDown);
%             
            % maps_axes
            axes(app.Dynamics_axes);
            app.Dynamic_slider= 1;
            app.Slice_slider = 1;
%             
              try
                app.Stack = app.Mpre_stack;  % initial stack is the pre-injection stac
                imagesc(app.Stack(:, :, app.Slice_slider, app.Dynamic_slider))
                catch
                imagesc(zeros(64, 64));
              end
%             
            colormap gray;
            axis off;
            %hold on;
            %contour(app.Mask , 1, 'm-','LineWidth',2)
            
            % norm_checkbox
            set(app.NormalizeCheckBox.value, 'string', '');
            
            % M0onoff_checkbox
            set(app.M0onCheckBox.value, 'Value', 1);
            
            % pixeloverlay_checkbox
            set(app.PixeloverlayCheckBox.value, 'Value', 0);
            
            
            % dynamics_axes
            axes(app.Dynamic_axis);
            set(app.Dynamic_axis,'YAxisLocation','right');
            xlabel('Dynamic number');
            ylabel('M_{z}');
            box on;
            
            % slice_slider - Slice range
            set(app.Slice_slider, 'Min', 1);
            
            try
                set(app.Slice_slider, 'Max', size(app.Stack, 3));
            catch
                set(app.Slice_slider, 'Max', 7);
            end
            set(app.Slice_slider, 'Value', 1);
            
            try
               set(app.Slice_slider, 'SliderStep', [1/(size(app.Stack, 3) - 1) 1/(size(app.Stack, 3) - 1)]);
             catch
               set(app.Slice_slider, 'SliderStep', [1/(20 - 1) 1/(20 - 1)]);
            end
%             set(app.slice_panel,'Title',sprintf('Slice No. = %d', 1));
%             
            % dynamics_slider - Dynamic range
            set(app.Dynamic_slider, 'Min', 1);
            try
                set(app.Dynamic_slider, 'Max', size(app.Stack, 4));
            catch
                set(app.Dynamic_slider, 'Max', 10);
            end
            set(app.Dynamic_slider, 'Value', 1);
            
            try
                set(app.Dynamic_slider, 'SliderStep', [1/(size(app.Stack, 4) - 1) 1/(size(app.Stack, 4) - 1)]);
            catch
                set(app.Dynamic_slider, 'SliderStep', [1/(20 - 1) 1/(20 - 1)]);
            end
%             set(app.dynamics_panel,'Title',sprintf('Dynamic No. = %d', 1));
%             
%             
%             set(gcf, 'WindowButtonDownFcn', @app.getMousePositionOnImage);
%             
%             % polyshape_radiobutton
%             set(app.polyshape_radiobutton, 'Value', 1);
%             
%             % roisno_edit
%             set(app.roisno_edit, 'String', 1);
%             
%             % mlfprespan_edit
%             set(app.mlfprespan_edit, 'String', 2);
%             
%             % mlfpostspan_edit
%             set(app.mlfpostspan_edit, 'String', 2);
%             
%             % prerangelb_edit
%             set(app.prerangelb_edit,'String', 7);
%             
%             % prerangeub_edit
%             set(app.prerangeub_edit,'String', 14);
%             
%             % postrangelb_edit
%             set(app.postrangelb_edit,'String', 7);
%             
%             % postrangeub_edit
%             set(app.postrangeub_edit,'String', 40);
%             
%             % threshold_edit
%             set(app.threshold_edit,'String', 5);
%             
%             % Mzdisp_radiobutton
%             set(app.Mzdisp_radiobutton, 'Value', 0);
%             
%             % MzM0disp_radiobutton
%             set(app.MzM0disp_radiobutton, 'Value', 0);
%             
%             % MTRdisp_radiobutton
%             set(app.MTRdisp_radiobutton, 'Value', 1);
%             
%             % inverseMTR_radiobutton
%             set(app.inverseMTR_radiobutton, 'Value', 1);
%             
%             % CESThistogram_radiobutton
%             set(app.CESThistogram_radiobutton, 'Value', 0);
%             
%             % MTRratiodisp_radiobutton
%             set(app.MTRratiodisp_radiobutton, 'Value', 0);
%             
%             % pHmapdisp_radiobutton
%             set(app.pHmapdisp_radiobutton, 'Value', 0);
%             
%             % pHhistogram_radiobutton
%             set(app.pHhistogram_radiobutton, 'Value', 0);
%             
%             % p1coeff_edit
%             set(app.p1coeff_edit,'String', -0.0237);
%             
%             % p2coeff_edit
%             set(app.p2coeff_edit,'String', 0.2509);
%             
%             % p3coeff_edit
%             set(app.p3coeff_edit,'String', -1.029);
%             
%             % p4coeff_edit
%             set(app.p4coeff_edit,'String', 7.508);
%             
%             % Update app structure
             guidata(hObject, app);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed2(app, event)
            foldername= uigetdir();
            app.FolderPathEditField.Value= foldername;
            figure(app.UIFigure);
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            % --- Executes on button press in ClearALLButton.
            
           
            % hObject    handle to ClearALLButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            evalin('base', 'clear all');
            evalin('base', 'clc');
            close ;
            MCMAHONAPP;
        end

        % Value changed function: Dynamic_slider
        function Dynamic_sliderValueChanged(app, event)
            
            % --- Executes on slider movement.
            
            
            % hObject    handle to dynamics_slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            axes(app.Dynamics_axes);
            app.dyn_pos = round(get(app.Dynamic_slider, 'Value'));
            
            UpdateSliders_Callback(app, event);
            %guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VIEWPanel = uipanel(app.UIFigure);
            app.VIEWPanel.Title = '                                                     VIEW';
            app.CostumizeView = uipanel(app.VIEWPanel);
            app.CostumizeView.Title = 'Costumize the view';
            app.StackDropDownLabel = uilabel(app.CostumizeView);
            app.StackDropDownLabel.Text = 'Stack';
            app.StackDropDown = uidropdown(app.CostumizeView);
            app.StackDropDown.Items = {'Pre-injection MCORR', 'Post-injection MCORR', 'WASSR', 'All'};
            app.StackDropDown.Value = 'Pre-injection MCORR';
            app.NormalizationDropDownLabel = uilabel(app.CostumizeView);
            app.NormalizationDropDownLabel.Text = 'Normalization';
            app.NormalizationDropDown = uidropdown(app.CostumizeView);
            app.NormalizationDropDown.Items = {'Pre First M0', 'Pre Last M0', 'Post First M0', 'Post Last M0', 'All'};
            app.NormalizationDropDown.Value = 'All';
            app.TabGroup = uitabgroup(app.VIEWPanel);
            app.IMAGETab = uitab(app.TabGroup);
            app.IMAGETab.Title = 'IMAGE';
            app.Dynamics_axes = uiaxes(app.IMAGETab);
            zlabel(app.Dynamics_axes, 'Z')
            app.ROIMAPTab = uitab(app.TabGroup);
            app.ROIMAPTab.Title = 'ROI MAP';
            app.ROI_map = uiaxes(app.ROIMAPTab);
            zlabel(app.ROI_map, 'Z')
            app.R2Tab = uitab(app.TabGroup);
            app.R2Tab.Title = 'R^2';
            app.R2_map = uiaxes(app.R2Tab);
            zlabel(app.R2_map, 'Z')
            app.HistogramEQTab = uitab(app.TabGroup);
            app.HistogramEQTab.Title = 'Histogram EQ';
            app.Histogram_eq = uiaxes(app.HistogramEQTab);
            zlabel(app.Histogram_eq, 'Z')
            app.PixelAnalyzerPanel = uipanel(app.UIFigure);
            app.PixelAnalyzerPanel.Title = 'Pixel Analyzer';
            app.Dynamic_axis = uiaxes(app.PixelAnalyzerPanel);
            xlabel(app.Dynamic_axis, 'Dynamic number')
            ylabel(app.Dynamic_axis, 'M_z')
            app.NormalizeCheckBox = uicheckbox(app.PixelAnalyzerPanel);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.PixeloverlayCheckBox = uicheckbox(app.PixelAnalyzerPanel);
            app.PixeloverlayCheckBox.Text = 'Pixel overlay';
            app.M0onCheckBox = uicheckbox(app.PixelAnalyzerPanel);
            app.M0onCheckBox.Text = 'M0 on';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Create pH Maps';
            app.MTRratioButton = uiradiobutton(app.ButtonGroup);
            app.MTRratioButton.Text = 'MTR ratio';
            app.MTRratioButton.Value = true;
            app.pHHistogramButton = uiradiobutton(app.ButtonGroup);
            app.pHHistogramButton.Text = 'pH Histogram';
            app.pHmapButton = uiradiobutton(app.ButtonGroup);
            app.pHmapButton.Text = 'pH map';
            app.CallibrationCurveCoefficientsPanel = uipanel(app.ButtonGroup);
            app.CallibrationCurveCoefficientsPanel.Title = 'Callibration Curve Coefficients';
            app.p1EditFieldLabel = uilabel(app.CallibrationCurveCoefficientsPanel);
            app.p1EditFieldLabel.Text = 'p1';
            app.p1EditField = uieditfield(app.CallibrationCurveCoefficientsPanel, 'numeric');
            app.p1EditField.Limits = [10 40];
            app.p1EditField.ValueDisplayFormat = '%.0f';
            app.p1EditField.Value = 30;
            app.p3EditFieldLabel = uilabel(app.CallibrationCurveCoefficientsPanel);
            app.p3EditFieldLabel.Text = 'p3';
            app.p3EditField = uieditfield(app.CallibrationCurveCoefficientsPanel, 'numeric');
            app.p3EditField.Limits = [10 40];
            app.p3EditField.ValueDisplayFormat = '%.0f';
            app.p3EditField.Value = 30;
            app.p2EditFieldLabel = uilabel(app.CallibrationCurveCoefficientsPanel);
            app.p2EditFieldLabel.Text = 'p2';
            app.p2EditField = uieditfield(app.CallibrationCurveCoefficientsPanel, 'numeric');
            app.p2EditField.Limits = [10 40];
            app.p2EditField.ValueDisplayFormat = '%.0f';
            app.p2EditField.Value = 30;
            app.p4EditFieldLabel = uilabel(app.CallibrationCurveCoefficientsPanel);
            app.p4EditFieldLabel.Text = 'p4';
            app.p4EditField = uieditfield(app.CallibrationCurveCoefficientsPanel, 'numeric');
            app.p4EditField.Limits = [10 40];
            app.p4EditField.ValueDisplayFormat = '%.0f';
            app.p4EditField.Value = 30;
            app.inverseButton = uiradiobutton(app.ButtonGroup);
            app.inverseButton.Text = 'inverse';
            app.MTRRatioLabel = uilabel(app.ButtonGroup);
            app.MTRRatioLabel.Text = 'MTR Ratio';
            app.COSTUMIZEPanel = uipanel(app.UIFigure);
            app.COSTUMIZEPanel.Title = '                                  COSTUMIZE';
            app.CostumizewithfilteringPanel = uipanel(app.COSTUMIZEPanel);
            app.CostumizewithfilteringPanel.Title = 'Costumize- with filtering';
            app.DefineROIsPanel = uipanel(app.CostumizewithfilteringPanel);
            app.DefineROIsPanel.Title = 'Define ROIs ';
            app.LOADROIsButton = uibutton(app.DefineROIsPanel, 'push');
            app.LOADROIsButton.Text = 'LOAD ROIs';
            app.LoanPeriodYearsEditFieldLabel_7 = uilabel(app.DefineROIsPanel);
            app.LoanPeriodYearsEditFieldLabel_7.Text = '# of ROIs';
            app.LoanPeriodYearsEditField_13 = uieditfield(app.DefineROIsPanel, 'numeric');
            app.LoanPeriodYearsEditField_13.Limits = [10 40];
            app.LoanPeriodYearsEditField_13.ValueDisplayFormat = '%.0f';
            app.LoanPeriodYearsEditField_13.Value = 30;
            app.RUNROIsButton = uibutton(app.DefineROIsPanel, 'push');
            app.RUNROIsButton.Text = 'RUN ROIs';
            app.ShapesButtonGroup = uibuttongroup(app.DefineROIsPanel);
            app.ShapesButtonGroup.Title = 'Shapes';
            app.FreeButton = uiradiobutton(app.ShapesButtonGroup);
            app.FreeButton.Text = 'Free';
            app.FreeButton.Value = true;
            app.PolyButton = uiradiobutton(app.ShapesButtonGroup);
            app.PolyButton.Text = 'Poly';
            app.ElipseButton = uiradiobutton(app.ShapesButtonGroup);
            app.ElipseButton.Text = 'Elipse';
            app.FreeGrowingButton = uiradiobutton(app.ShapesButtonGroup);
            app.FreeGrowingButton.Text = 'Free Growing';
            app.RsquaredthresholdingPanel = uipanel(app.CostumizewithfilteringPanel);
            app.RsquaredthresholdingPanel.Title = 'R squared thresholding';
            app.LoanPeriodYearsEditFieldLabel_6 = uilabel(app.RsquaredthresholdingPanel);
            app.LoanPeriodYearsEditFieldLabel_6.Text = 'R^2 Threshold';
            app.ApplyThresholdButton = uibutton(app.RsquaredthresholdingPanel, 'push');
            app.ApplyThresholdButton.Text = 'Apply Threshold';
            app.LoanPeriodYearsEditField_12 = uieditfield(app.RsquaredthresholdingPanel, 'numeric');
            app.LoanPeriodYearsEditField_12.Limits = [0 100];
            app.LoanPeriodYearsEditField_12.ValueDisplayFormat = '%.3f';
            app.LoanPeriodYearsEditField_12.Value = 95;
            app.Hyperlink = uihyperlink(app.RsquaredthresholdingPanel);
            app.Hyperlink.Text = '?';
            app.Slice_slider_Label = uilabel(app.CostumizewithfilteringPanel);
            app.Slice_slider_Label.Text = 'Slice No.';
            app.Slice_slider = uislider(app.CostumizewithfilteringPanel);
            app.Slice_slider.Limits = [1 100];
            app.Slice_slider.Value = 1;
            app.movingAveragesPanel = uipanel(app.COSTUMIZEPanel);
            app.movingAveragesPanel.Title = 'moving Averages';
            app.LoanPeriodYearsEditFieldLabel_4 = uilabel(app.movingAveragesPanel);
            app.LoanPeriodYearsEditFieldLabel_4.Text = 'Pre span';
            app.ApplyFilterButton = uibutton(app.movingAveragesPanel, 'push');
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.LoanPeriodYearsEditField_8 = uieditfield(app.movingAveragesPanel, 'numeric');
            app.LoanPeriodYearsEditField_8.Limits = [10 40];
            app.LoanPeriodYearsEditField_8.ValueDisplayFormat = '%.0f';
            app.LoanPeriodYearsEditField_8.Value = 30;
            app.LoanPeriodYearsEditFieldLabel_5 = uilabel(app.movingAveragesPanel);
            app.LoanPeriodYearsEditFieldLabel_5.Text = 'Post Span';
            app.LoanPeriodYearsEditField_10 = uieditfield(app.movingAveragesPanel, 'numeric');
            app.LoanPeriodYearsEditField_10.Limits = [10 40];
            app.LoanPeriodYearsEditField_10.ValueDisplayFormat = '%.0f';
            app.LoanPeriodYearsEditField_10.Value = 30;
            app.SETUPPanel = uipanel(app.UIFigure);
            app.SETUPPanel.Title = '                             SETUP';
            app.InitiallizingPanel = uipanel(app.SETUPPanel);
            app.InitiallizingPanel.Title = 'Initiallizing ';
            app.FolderPathEditFieldLabel = uilabel(app.InitiallizingPanel);
            app.FolderPathEditFieldLabel.Text = 'Folder Path';
            app.FolderPathEditField = uieditfield(app.InitiallizingPanel, 'text');
            app.FolderPathEditField.ValueChangingFcn = createCallbackFcn(app, @FolderPathEditFieldValueChanging, true);
            app.LOADButton = uibutton(app.InitiallizingPanel, 'push');
            app.LOADButton.Text = 'LOAD';
            app.SAVEButton = uibutton(app.InitiallizingPanel, 'push');
            app.SAVEButton.Text = 'SAVE';
            app.ClearAllButton = uibutton(app.InitiallizingPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.CloseButton = uibutton(app.InitiallizingPanel, 'push');
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.InitiallizingPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed2, true);
            app.OpenButton.Text = 'Open';
            app.STEP2RUNPanel = uipanel(app.SETUPPanel);
            app.STEP2RUNPanel.Title = 'STEP 2- RUN';
            app.medianfilterCheckBox = uicheckbox(app.STEP2RUNPanel);
            app.medianfilterCheckBox.Text = 'median filter';
            app.Pre_injection_label = uilabel(app.STEP2RUNPanel);
            app.Pre_injection_label.Text = 'Pre injection';
            app.Pre_injection_field = uieditfield(app.STEP2RUNPanel, 'numeric');
            app.Pre_injection_field.Limits = [0 20];
            app.Pre_injection_field.ValueDisplayFormat = '%.0f';
            app.post_injection_label = uilabel(app.STEP2RUNPanel);
            app.post_injection_label.Text = 'post-injection';
            app.post_injection_field = uieditfield(app.STEP2RUNPanel, 'numeric');
            app.post_injection_field.Limits = [0 40];
            app.post_injection_field.ValueDisplayFormat = '%.0f';
            app.post_injection_field.ValueChangedFcn = createCallbackFcn(app, @FolderPathEditFieldValueChanging, true);
            app.WASSRLabel = uilabel(app.STEP2RUNPanel);
            app.WASSRLabel.Text = 'WASSR';
            app.WASSRfield = uieditfield(app.STEP2RUNPanel, 'numeric');
            app.WASSRfield.Limits = [0 40];
            app.WASSRfield.ValueDisplayFormat = '%.0f';
            app.Step2_run_button = uibutton(app.STEP2RUNPanel, 'push');
            app.Step2_run_button.Text = 'RUN';
            app.Panel = uipanel(app.UIFigure);
            app.NOTESTextAreaLabel = uilabel(app.Panel);
            app.NOTESTextAreaLabel.Text = 'NOTES:  ';
            app.NOTESTextArea = uitextarea(app.Panel);
            app.SAVENOTESButton = uibutton(app.Panel, 'push');
            app.SAVENOTESButton.Text = 'SAVE NOTES';
            app.Dynamic_slider_label = uilabel(app.UIFigure);
            app.Dynamic_slider_label.Text = 'Dynamic No.';
            app.Dynamic_slider = uislider(app.UIFigure);
            app.Dynamic_slider.Limits = [1 100];
            app.Dynamic_slider.ValueChangedFcn = createCallbackFcn(app, @Dynamic_sliderValueChanged, true);
            app.Dynamic_slider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MCMAHONAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end