classdef Mcmahonappv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel_2                        matlab.ui.container.Panel
        M0onCheckBox                   matlab.ui.control.CheckBox
        PixeloverlayCheckBox           matlab.ui.control.CheckBox
        NormalizeCheckBox              matlab.ui.control.CheckBox
        Panel_3                        matlab.ui.container.Panel
        InverseCheckBox                matlab.ui.control.CheckBox
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        pHHistogramButton_2            matlab.ui.control.RadioButton
        pHmapButton_2                  matlab.ui.control.RadioButton
        MTRratioButton_2               matlab.ui.control.RadioButton
        threshold_run_2                matlab.ui.control.Button
        CallibrationCurveCoefficientsPanel  matlab.ui.container.Panel
        MTRRatioLabel                  matlab.ui.control.Label
        p4EditField                    matlab.ui.control.NumericEditField
        p4EditFieldLabel               matlab.ui.control.Label
        p2EditField                    matlab.ui.control.NumericEditField
        p2EditFieldLabel               matlab.ui.control.Label
        p3EditField                    matlab.ui.control.NumericEditField
        p3EditFieldLabel               matlab.ui.control.Label
        p1EditField                    matlab.ui.control.NumericEditField
        p1EditFieldLabel               matlab.ui.control.Label
        ShapesButtonGroup              matlab.ui.container.ButtonGroup
        FreeGrowingButton              matlab.ui.control.RadioButton
        ElipseButton                   matlab.ui.control.RadioButton
        PolyButton                     matlab.ui.control.RadioButton
        FreeButton                     matlab.ui.control.RadioButton
        RsquaredthresholdingPanel      matlab.ui.container.Panel
        ApplyThresholdButton           matlab.ui.control.Button
        Heq_threshold                  matlab.ui.control.NumericEditField
        LoanPeriodYearsEditFieldLabel_2  matlab.ui.control.Label
        movingAveragesPanel            matlab.ui.container.Panel
        post_span                      matlab.ui.control.NumericEditField
        LoanPeriodYearsEditFieldLabel_4  matlab.ui.control.Label
        ApplyFilterButton              matlab.ui.control.Button
        pre_span                       matlab.ui.control.NumericEditField
        LoanPeriodYearsEditFieldLabel_3  matlab.ui.control.Label
        DefineROIsPanel                matlab.ui.container.Panel
        roi_pushbutton                 matlab.ui.control.Button
        roisno_edit                    matlab.ui.control.NumericEditField
        LoanPeriodYearsEditFieldLabel  matlab.ui.control.Label
        loadROIs_pushbutton            matlab.ui.control.Button
        SubstractAverageandMaskPanel   matlab.ui.container.Panel
        Mzdisp_val                     matlab.ui.control.CheckBox
        AverageCESTSignalButtonGroup   matlab.ui.container.ButtonGroup
        minrange                       matlab.ui.control.EditField
        offsetsEditFieldLabel          matlab.ui.control.Label
        minrange_val                   matlab.ui.control.RadioButton
        average_val                    matlab.ui.control.RadioButton
        sigenhanc_val                  matlab.ui.control.CheckBox
        Post_range_Label               matlab.ui.control.Label
        postrange_start                matlab.ui.control.NumericEditField
        toLabel_2                      matlab.ui.control.Label
        toLabel                        matlab.ui.control.Label
        prerange_end                   matlab.ui.control.NumericEditField
        pre_range_label_2              matlab.ui.control.Label
        CESThistogramdisp_val          matlab.ui.control.CheckBox
        MzM0disp_val                   matlab.ui.control.CheckBox
        postrange_end                  matlab.ui.control.NumericEditField
        MTRdisp_val                    matlab.ui.control.CheckBox
        prerange_start                 matlab.ui.control.NumericEditField
        contrast_thresh                matlab.ui.control.NumericEditField
        Contrastthreshold_label        matlab.ui.control.Label
        threshold_run                  matlab.ui.control.Button
        pre_range_label                matlab.ui.control.Label
        Contrastthreshold_label_2      matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        SAVENOTESButton                matlab.ui.control.Button
        NOTESTextArea                  matlab.ui.control.TextArea
        NOTESTextAreaLabel             matlab.ui.control.Label
        SETUPPanel                     matlab.ui.container.Panel
        STEP2RUNPanel                  matlab.ui.container.Panel
        Step2_run_button               matlab.ui.control.Button
        WASSRfield                     matlab.ui.control.NumericEditField
        WASSRLabel                     matlab.ui.control.Label
        post_injection_field           matlab.ui.control.NumericEditField
        post_injection_label           matlab.ui.control.Label
        Pre_injection_field            matlab.ui.control.NumericEditField
        Pre_injection_label            matlab.ui.control.Label
        medianfilterCheckBox           matlab.ui.control.CheckBox
        InitiallizingPanel             matlab.ui.container.Panel
        OpenButton                     matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        ClearAllButton                 matlab.ui.control.Button
        SAVEButton                     matlab.ui.control.Button
        LOADButton                     matlab.ui.control.Button
        FolderPathEditField            matlab.ui.control.EditField
        FolderPathEditFieldLabel       matlab.ui.control.Label
        CUSTOMIZEPanel                 matlab.ui.container.Panel
        PixelAnalyzerPanel             matlab.ui.container.Panel
        Dynamic_axis                   matlab.ui.control.UIAxes
        VIEWPanel                      matlab.ui.container.Panel
        nbins                          matlab.ui.control.Slider
        nbins_label                    matlab.ui.control.Label
        Slice_slider                   matlab.ui.control.Slider
        Dynamic_slider                 matlab.ui.control.Slider
        Dynamic_slider_label           matlab.ui.control.Label
        Slice_slider_Label             matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        IMAGETab                       matlab.ui.container.Tab
        Image_view                     matlab.ui.control.UIAxes
        ROIMAPTab                      matlab.ui.container.Tab
        ROI_map                        matlab.ui.control.UIAxes
        R2Tab                          matlab.ui.container.Tab
        R2_map                         matlab.ui.control.UIAxes
        HistogramEQTab                 matlab.ui.container.Tab
        Histogram_eq                   matlab.ui.control.UIAxes
        CostumizeView                  matlab.ui.container.Panel
        NormalizationDropDown          matlab.ui.control.DropDown
        NormalizationDropDownLabel     matlab.ui.control.Label
        StackDropDown                  matlab.ui.control.DropDown
        StackDropDownLabel             matlab.ui.control.Label
    end


    properties (Access = public)
        % new global variables to be used in the program
        Stack = struct();
        WASSRdata = struct();
        CESTdata = struct();
        offset_Hz_full;
        M0start_no;
        M0end_no;
        Stack_dim;
        I_current
        ROIdef_full;
        Mask_full;
        noOfROIs;
        Mask_full_threshold;
        ROIdef_full_threshold;
        hist_mask_full;
        CESTresults= struct();
        saved_file_path;
        saved_folder;
        saved_dir;
        filenm;
        oldfile;
    end

    methods (Access = public)

        function UpdateSliders_Callback(app, event)

            % interactive sliders
            axes(app.Image_view);


            app.nbins_label.Text = sprintf('Bin No.= %d', round(app.nbins.Value));
            app.Slice_slider_Label.Text = sprintf('Slice No. = %d', round(app.Slice_slider.Value));
            app.Dynamic_slider_label.Text = sprintf('Dynamic No. = %d', round(app.Dynamic_slider.Value));
            if round(app.Slice_slider.Value) <= size(app.Stack, 3) && round(app.Dynamic_slider.Value) <= size(app.Stack, 4) % if popt parameters are selected
                imagesc(app.Image_view, app.Stack(:, :, round(app.Slice_slider.Value), round(app.Dynamic_slider.Value)));
                axis(app.Image_view, 'off');
                app.I_current= app.Stack(:, :, round(app.Slice_slider.Value), round(app.Dynamic_slider.Value));

                imagesc(app.Histogram_eq, histeq(app.I_current,round(app.nbins.Value)));
                axis(app.Histogram_eq, 'off');
                colormap (app.Histogram_eq , 'gray');

                if ~isempty(app.Mask_full)
                    try
                        %  imagesc(app.Histogram_eq, histeq( app.Mask_full(:, :, round(app.Slice_slider.Value), ) );
                        %axis(app.Histogram_eq, 'off');
                        %axis(app.Histogram_eq,  'equal');
                        %colormap (app.Histogram_eq, 'gray');


                        imagesc(app.ROI_map ,app.Mask_full(:, :, round(app.Slice_slider.Value)));
                        axis(app.ROI_map,  'off');
                        axis(app.ROI_map,  'equal');
                        colormap (app.ROI_map , 'gray');
                    catch

                        %displaying ROI map
                        imagesc(app.ROI_map ,app.Mask_full(:, :, sl));
                        axis(app.ROI_map,  'off');
                        axis(app.ROI_map,  'equal');
                        colormap (app.ROI_map , 'gray');


                    end
                end

            else
                imagesc(app.Stack(:, :, 1, 1));
            end


        end

        %         function getMousePositionOnImage (app)
        %
        %
        %             cursorPoint = get(app.Image_view, 'CurrentPoint');
        %             x_pos = uint16(cursorPoint(1,1));
        %             y_pos = uint16(cursorPoint(1,2));
        %
        %             xLimits = get(app.Image_view, 'xlim');
        %             yLimits = get(app.Image_view, 'ylim');
        %
        %
        %             if (x_pos > min(xLimits) && x_pos < max(xLimits) && y_pos > min(yLimits) && y_pos < max(yLimits))
        %             disp(['Position (' num2str(x_pos) ', ' num2str(y_pos) ').']);
        %
        %             %round(app.Slice_slider.Value) = round(get(app.slice_slider,'Value'));
        %
        %
        %             popup_sel_index = app.StackDropDown.Value;
        %             norm_checkbox_val = app.NormalizeCheckBox.Value;
        %             M0onoff_checkbox_val = app.M0onCheckBox.Value;
        %             pixeloverlay_checkbox_val = app.PixeloverlayCheckBox.Value;
        %
        %             if norm_checkbox_val == 1 && popup_sel_index == 1
        %                 app.Stack = double(app.CESTdata(1).Mz_stack_norm);
        %             elseif norm_checkbox_val == 1 && popup_sel_index == 2
        %                 app.Stack = double(app.CESTdata(2).Mz_stack_norm);
        %             end
        %
        %             if popup_sel_index == 5
        %                 axes(app.Dynamic_axis);
        %                 plot(app.WASSRdata.offset_ppm, squeeze(app.WASSRdata.Mz_stack_norm(y_pos, x_pos, round(app.Slice_slider.Value), :)));
        %                 set(app.Dynamic_axis,'YAxisLocation','right');
        %
        %                 axis([min(app.WASSRdata.offset_ppm) max(app.WASSRdata.offset_ppm)  0 1.0])
        %                 xticks(round([min(app.WASSRdata.offset_ppm):0.3:max(app.WASSRdata.offset_ppm)],2))
        %                 ylabel('M_{z}/M_{0}');
        %                 xlabel('Frequency Offset (ppm)');
        %                if pixeloverlay_checkbox_val == 0
        %                     hold(app.Dynamic_axis, 'off')
        %                else
        %                     hold(app.Dynamic_axis, 'on')
        %                end
        %             else
        %             if ~isempty(app.Pre_injection_field) || ~isempty(app.post_injection_field)
        %                 % CEST Data
        %                 for i = 1:numel(app.CESTdata(1).diffoffsets) - 1
        %
        %                     [~, c_ind] = find(app.offset_Hz_full == app.CESTdata(1).diffoffsets(i), 10000);
        %                     Stack_offsets = app.Stack(:, :, :, c_ind);
        %                     offsets_ind = app.CESTdata(1).Mz_stack(i).Mz_ind;
        %                     [cmax, cind_max]  = max(diff(offsets_ind));
        %
        %                     if cmax > 1
        %
        %                     end
        %
        %                     if M0onoff_checkbox_val == 1
        %                        % Full_Stack_offsets = [squeeze(app.Stack(y_pos, x_pos, round(app.Slice_slider.Value), 1:app.M0start_no)); squeeze(Stack_offsets(y_pos, x_pos, round(app.Slice_slider.Value), :)); squeeze(app.Stack(y_pos, x_pos, round(app.Slice_slider.Value), app.Stack_dim(4)-app.M0end_no+1:app.Stack_dim(4)))];
        %                         Full_Stack_offsets = [squeeze(app.Stack(y_pos, x_pos, round(app.Slice_slider.Value), 1:app.M0start_no)); squeeze(app.Stack(y_pos, x_pos, round(app.Slice_slider.Value), app.CESTdata(1).Mz_stack(i).Mz_ind)); squeeze(app.Stack(y_pos, x_pos, round(app.Slice_slider.Value), app.Stack_dim(4)-app.M0end_no+1:app.Stack_dim(4)))];
        %
        %                     else
        %                         %Full_Stack_offsets = squeeze(Stack_offsets(y_pos, x_pos, round(app.Slice_slider.Value), :));
        %                         Full_Stack_offsets = squeeze(app.Stack(y_pos, x_pos, round(app.Slice_slider.Value), app.CESTdata(1).Mz_stack(i).Mz_ind));
        %                     end
        %
        %                     % Plot the CEST data
        %                     plot(app.Dynamic_axis, Full_Stack_offsets, '.-');
        %                     %plot(app.Dynamic_axis, [1:app.M0start_no app.CESTdata(1).Mz_stack(i).Mz_ind app.Stack_dim(4)-app.M0end_no+1:app.Stack_dim(4)], Full_Stack_offsets' ,'.-'); hold on;
        %                     set(app.Dynamic_axis,'YAxisLocation','right');
        %                     axes(app.Dynamic_axis);
        %                     %xlim([1 xulim])
        %                     if norm_checkbox_val == 1
        %                         ylabel('M_{z}/M_{0}');
        %                     else
        %                         ylabel('M_{z}');
        %                     end
        %                     xlabel('Dynamic number');
        %
        %                     hold(app.Dynamic_axis, 'on')
        %                 end
        %                if pixeloverlay_checkbox_val == 0
        %                     hold( app.Dynamic_axis, 'off')
        %                end
        %             end
        %             end
        %             else
        %             disp('Cursor is outside bounds of image.');
        %             return
        %             end
        %
        %         end
        function posttimepoints_edit_CreateFcn (app, event)
            % --- Executes during object creation, after setting all properties.

            % hObject    handle to posttimepoints_edit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    empty - app not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(app,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(app,'BackgroundColor','white');
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % --- Executes just before ratioCEST_gui is made visible.

            % Ensure that the app appears on screen when run
            %movegui(app.UIFigure, 'onscreen');


            app.Dynamic_slider.Value = 1; % give explanation
            % give explanation
            app.Slice_slider.Value=1;
            try
                app.Stack= app.Mpre_stack;  % initial stack is the pre-injection stac ---- give explanation
                imagesc(app.Stack(:, :, event.slice_pos, event.dyn_pos))
            catch
                imagesc(app.Image_view, zeros(64, 64));
            end

            colormap(app.Image_view, 'gray');
            axis(app.Image_view, 'off');
            %hold on;
            %contour(app.Mask , 1, 'm-','LineWidth',2)

            % norm_checkbox
            app.NormalizeCheckBox.Value = 0;

            % M0onoff_checkbox
            app.M0onCheckBox.Value = 0;

            % pixeloverlay_checkbox
            app.PixeloverlayCheckBox.Value = 0;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            foldername = uigetdir();
            app.FolderPathEditField.Value = foldername;
            figure(app.UIFigure);
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            % --- Executes on button press in ClearALLButton.
            % hObject    handle to ClearALLButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            evalin('base', 'clear all');
            evalin('base', 'clc');
        end

        % Value changed function: Dynamic_slider, nbins
        function Dynamic_sliderValueChanged(app, event)

            % --- Executes on slider movement.


            % hObject    handle to dynamic_slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)

            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            %axes(app.Image_view);
            %app.Dynamic_slider.dyn_pos = round(app.Dynamic_slider.Value);
            app.Dynamic_slider.Value = round(app.Dynamic_slider.Value);
            UpdateSliders_Callback(app, event);
            %guidata(hObject, app);
        end

        % Button pushed function: Step2_run_button
        function Step2_run_buttonButtonPushed(app, event)
            % --- Executes on button press in load_button.

            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle o load_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            %             app.FolderPathEditField.Value
            %             app.FolderPathEditField.Value = get(app.FolderPathEditField.Value);
            %             app.Pre_injection_field.Value = get(app.Pre_injection_field.Value);
            %             app.post_injection_field.Value= get(app.post_injection_field.Value);
            %             app.WASSRfield.Value = get(app.WASSRfield.Value);
            %           app.medianfilterCheckBox.Value = get(app.medianfilterCheckBox.Value);
            %
            %             app.pre_M0start_radiobutton = get(app.pre_M0start_radiobutton.Value);
            %             app.pre_M0end_radiobutton = get(app.pre_M0end_radiobutton.Value);
            %             app.post_M0start_radiobutton = get(app.post_M0start_radiobutton.Value);
            %             app.post_M0end_radiobutton = get(app.post_M0end_radiobutton.Value);
            app.NormalizationDropDown.Value;

            if strcmp(app.NormalizationDropDown.Value, 'Pre First M0')
                M0normSel = 'M0pre_start';
            elseif strcmp(app.NormalizationDropDown.Value, 'Pre Last M0')
                M0normSel = 'M0pre_end';
            elseif strcmp(app.NormalizationDropDown.Value, 'Post First M0')
                M0normSel = 'M0post_start';
            elseif strcmp(app.NormalizationDropDown.Value, 'Post Last M0')
                M0normSel = 'M0post_end';
            end

            exp_no= {app.Pre_injection_field.Value, app.post_injection_field.Value};

            % Load CEST data
            if ~isempty(app.Pre_injection_field.Value) || ~isempty(app.post_injection_field.Value)
                app.CESTdata = loadCESTdata(exp_no, app.FolderPathEditField.Value, app.medianfilterCheckBox.Value);

                CESTdata = app.CESTdata;

            else
                disp('CEST Data not loaded');
            end
            % Load WASSR data
            if ~isempty(app.WASSRfield)
                app.WASSRdata = loadWASSRdata(app.WASSRfield.Value, app.FolderPathEditField.Value, app.medianfilterCheckBox.Value);

                assignin('base', 'WASSRdata', app.WASSRdata);
            else
                disp('WASSR Data not loaded');
            end

            StackDropDownValueChanged(app, event);

            % Show pre-injection stack data
            %app.Stack = app.CESTdata(1).Mz_stack_full;

            try
                app.Stack = app.CESTdata(1).Mz_stack_full;  % initial stack is the pre-injection stac
                app.offset_Hz_full.Value = app.CESTdata(1).offset_Hz_full;
                app.M0start_no.Value = app.CESTdata(1).M0start_no;
                app.M0end_no.Value = app.CESTdata(1).M0end_no;
                app.Stack_dim.Value = app.CESTdata(1).stack_dim;
            catch
                app.Stack = app.WASSRdata.Mz_stack_full;
            end


            %             %set(app.Dynamic_slider, 'Max', size(app.Stack, 4))

            app.Dynamic_slider.Limits(2) = size(app.Stack, 4) ;
            % set(app.Dynamic_slider, 'SliderStep', [1/(size(app.Stack, 4) - 1) 1/(size(app.Stack, 4) - 1)]);
            app.Dynamic_slider.MajorTicks = [1/(size(app.Stack, 4) - 1) 1/(size(app.Stack, 4) - 1)];
            axes(app.Image_view);

            if round(app.Slice_slider.Value) <= size(app.Stack, 3) && round(app.Dynamic_slider.Value) <= size(app.Stack, 4)
                imagesc(app.Image_view, app.Stack(:, :, round(app.Slice_slider.Value), round(app.Dynamic_slider.Value))) % initial stack is the pre-injection stack
                colormap(app.Image_view, 'gray') ;
                axis(app.Image_view, 'off') ;
            end
            colormap(app.Image_view, 'gray') ;
            axis(app.Image_view, 'off') ;

            % Normalize CEST data
            if ~isempty(app.Pre_injection_field) || ~isempty(app.post_injection_field)
                app.CESTdata = normalizeCESTdata(app.CESTdata, M0normSel);

                assignin('base', 'CESTdata', app.CESTdata);
            end
            %      getMousePositionOnImage (app, event)


            % this section is about setting up and saving the work in a given
            % folder the folders go by week and files are time stamped.
            t2= floor(now);
            d2_date = datetime(t2,'ConvertFrom','datenum', 'Format', 'yyyyMM');
            d2_temp= datetime(t2,'ConvertFrom','datenum');
            w = week(d2_temp);
            app.saved_folder = 'results_'+ string(d2_date) + string(w);
            app.saved_file_path = pwd;
            if contains(app.saved_file_path, app.saved_folder) == 1
                app.saved_dir = app.saved_file_path;
            else
                if not(isfolder(app.saved_folder))
                    mkdir(app.saved_folder);

                    app.saved_dir = strcat(app.saved_file_path, '/' , app.saved_folder);


                else
                    app.saved_file_path = pwd;


                    app.saved_dir = strcat(app.saved_file_path, '/' , app.saved_folder);
                end
            end
            [filepath, name, ~] = fileparts(app.saved_file_path);
            app.filenm = sprintf('%s.mat',datetime('now', 'Format', 'yyyy-MM-dd''T''HH-mm-ss-SSS'));
            %save(filenm);
        end

        % Value changed function: StackDropDown
        function StackDropDownValueChanged(app, event)
            %value = app.StackDropDown.Value;

            % --- Executes on selection change in maps_popupmenu.

            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maps_popupmenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns maps_popupmenu contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from maps_popupmenu

            axes(app.Image_view);
            axis(app.Image_view, 'off');
            colorbar(app.Image_view);
            cla(app.Image_view);

            if isempty(app.Pre_injection_field.Value) || isempty(app.post_injection_field.Value)
                popup_sel_index = 'WASSR';
            else
                popup_sel_index = app.StackDropDown.Value;
            end

            switch popup_sel_index
                case 'Pre-injection' % Stack = Pre-injection


                    try
                        app.Stack = double(app.CESTdata(1).Mz_stack_full);
                    catch
                        app.Stack = imagesc(app.Image_view,zeros(64, 64));
                    end
                    set(app.Slice_slider, 'value', 1);
                    set(app.Dynamic_slider, 'value', 1);
                    UpdateSliders_Callback(app, event);
                    app.offset_Hz_full = app.CESTdata(1).offset_Hz_full;
                    app.M0start_no = app.CESTdata(1).M0start_no;
                    app.M0end_no = app.CESTdata(1).M0end_no;
                    app.Stack_dim = app.CESTdata(1).stack_dim;

                case 'Post-injection' % Stack = Post-injection
                    app.Stack = double(app.CESTdata(2).Mz_stack_full);
                    set(app.Slice_slider, 'value', 1);
                    set(app.Dynamic_slider, 'value', 1);
                    UpdateSliders_Callback(app, event);
                    app.offset_Hz_full = app.CESTdata(2).offset_Hz_full;
                    app.M0start_no = app.CESTdata(2).M0start_no;
                    app.M0end_no = app.CESTdata(2).M0end_no;
                    app.Stack_dim = app.CESTdata(2).stack_dim;

                case 'Pre-injection MCORR' % Stack = Pre-injection mocorr
                    try
                        app.Stack = double(app.CESTdata(1).Mz_stack_mocorr_sort_full);
                    catch
                        app.Stack = double(app.CESTdata(1).Mz_stack_full);
                    end
                    set(app.Slice_slider, 'value', 1);
                    set(app.Dynamic_slider, 'value', 1);
                    UpdateSliders_Callback(app, event);
                    app.offset_Hz_full = app.CESTdata(1).offset_Hz_full;
                    app.M0start_no = app.CESTdata(1).M0start_no;
                    app.M0end_no = app.CESTdata(1).M0end_no;
                    app.Stack_dim = app.CESTdata(1).stack_dim;

                case 'Post-injection MCORR' % Stack = Post-injection mocorr
                    try
                        app.Stack = double(app.CESTdata(2).Mz_stack_mocorr_sort_full);
                    catch
                        app.Stack = double(app.CESTdata(2).Mz_stack_full);
                    end

                    set(app.Slice_slider, 'value', 1);
                    set(app.Dynamic_slider, 'value', 1);
                    UpdateSliders_Callback(app, event);
                    app.offset_Hz_full = app.CESTdata(2).offset_Hz_full;
                    app.M0start_no = app.CESTdata(2).M0start_no;
                    app.M0end_no = app.CESTdata(2).M0end_no;
                    app.Stack_dim = app.CESTdata(2).stack_dim;

                case 'WASSR' % Stack = WASSR data
                    try
                        app.Stack = double(app.WASSRdata.Mz_stack_full);
                    catch
                        app.Stack = double(app.CESTdata(2).Mz_stack_full);
                    end

                    set(app.Slice_slider, 'value', 1);
                    set(app.Dynamic_slider, 'value', 1);
                    UpdateSliders_Callback(app, event);
                    app.offset_Hz_full = app.WASSRdata.offset_Hz_full;
                    app.Stack_dim = app.WASSRdata.stack_dim;
            end

            %set(app.Dynamic_slider, 'Max', size(app.Stack, 4))
            app.Dynamic_slider.Limits(2) = size(app.Stack,4);
            %set(app.Dynamic_slider, 'SliderStep', [1/(size(app.Stack, 4) - 1) 1/(size(app.Stack, 4) - 1)]);
            app.Dynamic_slider.MajorTicks = [1/(size(app.Stack, 4) - 1) 1/(size(app.Stack, 4) - 1)];


            if size(app.Stack, 3) > 1

                app.Slice_slider.Limits(2) = size(app.Stack,3);
                %set(app.Slice_slider, 'SliderStep', [1/(size(app.Stack, 3) - 1) 1/(size(app.Stack, 3) - 1)]);
                app.Slice_slider.MajorTicks = [1/(size(app.Stack, 3) - 1) 1/(size(app.Stack, 3) - 1)];


            else
                set(app.Slice_slider,'visible','off')
            end

            %imagesc(app.Stack(:, :, round(app.Slice_slider.Value), round(app.Dynamic_slider.Value)));

            if round(app.Slice_slider.Value) <= size(app.Stack, 3) && round(app.Dynamic_slider.Value) <= size(app.Stack, 4) % if popt parameters are selected
                imagesc(app.Image_view ,app.Stack(:, :, round(app.Slice_slider.Value), round(app.Dynamic_slider.Value)));
                axis (app.Image_view , 'off');
            else
                imagesc(app.Image_view, app.Stack(:, :, 1, 1));
            end
            axis (app.Image_view, 'off');
            colormap (app.Image_view, 'gray');

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close all;
        end

        % Button pushed function: loadROIs_pushbutton
        function loadROIs_pushbuttonPushed(app, event)

            % --- Executes on button press in loadROIs_pushbutton.


            % loads the ROIs specificaly two variables
            [filenm_s,pathnm] = uigetfile('*.mat');
            app.oldfile = load(fullfile(pathnm,filenm_s), 'ROIdef_full_saved', 'Mask_full_saved');
            fprintf('ROIs loaded from %s\n', fullfile(path,file))

            try
                app.ROIdef_full = app.oldfile.ROIdef_full_saved;
                app.Mask_full = app.oldfile.Mask_full_saved;
            catch
                display_catch = 'no variables found/loaded'
            end

        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            % --- Executes on button press in mlf_pushbutton.


            % hObject    handle to mlf_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)

            mlfprespan_val = app.pre_span.Value;
            mlfpostspan_val = app.post_span.Value;

            app.CESTdata = applyMovingAverageFilter(app.CESTdata, [mlfprespan_val; mlfpostspan_val]);

            assignin('base', 'CESTdata', app.CESTdata);

        end

        % Window button down function: UIFigure
        function Image_viewButtonDown(app, event)
            cursorPoint = get(app.Image_view, 'CurrentPoint');
            x_pos = uint16(cursorPoint(1,1));
            y_pos = uint16(cursorPoint(1,2));

            xLimits = get(app.Image_view, 'xlim');
            yLimits = get(app.Image_view, 'ylim');


            if (x_pos > min(xLimits) && x_pos < max(xLimits) && y_pos > min(yLimits) && y_pos < max(yLimits))
                disp(['Position (' num2str(x_pos) ', ' num2str(y_pos) ').']);

                %round(app.Slice_slider.Value) = round(get(app.slice_slider,'Value'));


                popup_sel_index = app.StackDropDown.Value;
                norm_checkbox_val = app.NormalizeCheckBox.Value;
                M0onoff_checkbox_val = app.M0onCheckBox.Value;
                pixeloverlay_checkbox_val = app.PixeloverlayCheckBox.Value;

                if norm_checkbox_val == 1 && strcmp (popup_sel_index,'Pre-injection')
                    app.Stack = double(app.CESTdata(1).Mz_stack_norm);
                elseif norm_checkbox_val == 1 && strcmp (popup_sel_index, 'Post-injection')
                    app.Stack = double(app.CESTdata(2).Mz_stack_norm);
                end

                if  strcmp (popup_sel_index, 'WASSR')
                    axes(app.Dynamic_axis);
                    plot(app.WASSRdata.offset_ppm, squeeze(app.WASSRdata.Mz_stack_norm(y_pos, x_pos, round(app.Slice_slider.Value), :)));
                    set(app.Dynamic_axis,'YAxisLocation','right');

                    axis([min(app.WASSRdata.offset_ppm) max(app.WASSRdata.offset_ppm)  0 1.0])
                    xticks(round([min(app.WASSRdata.offset_ppm):0.3:max(app.WASSRdata.offset_ppm)],2))
                    ylabel(app.Dynamic_axis , 'M_{z}/M_{0}');
                    xlabel(app.Dynamic_axis, 'Frequency Offset (ppm)');
                    if pixeloverlay_checkbox_val == 0
                        hold(app.Dynamic_axis, 'off')
                    else
                        hold(app.Dynamic_axis, 'on')
                    end
                else
                    if ~isempty(app.Pre_injection_field.Value) || ~isempty(app.post_injection_field.Value)
                        % CEST Data
                        for i = 1:numel(app.CESTdata(1).diffoffsets) - 1

                            [~, c_ind] = find(app.offset_Hz_full == app.CESTdata(1).diffoffsets(i), 10000);
                            offsets_ind = app.CESTdata(1).Mz_stack(i).Mz_ind;
                            [cmax, cind_max]  = max(diff(offsets_ind));

                            %{'Pre-injection', 'Post-injection', 'WASSR', 'T2w', 'Pre-injection mocorr', 'Post-injection mocorr'}

                            if  strcmp(app.StackDropDown.Value, 'Pre-injection') || strcmp(app.StackDropDown.Value, 'Pre-injection mocorr')
                                Full_Stack_offsets = [squeeze(app.Stack(y_pos, x_pos, round(app.Slice_slider.Value), 1:app.M0start_no)); squeeze(app.Stack(y_pos, x_pos, round(app.Slice_slider.Value), app.CESTdata(1).Mz_stack(i).Mz_ind)); squeeze(app.Stack(y_pos, x_pos, round(app.Slice_slider.Value), app.Stack_dim(4)-app.M0end_no+1:app.Stack_dim(4)))];
                            elseif strcmp(app.StackDropDown.Value, 'Post-injection') || strcmp(app.StackDropDown.Value, 'Post-injection mocorr')
                                Full_Stack_offsets = [squeeze(app.Stack(y_pos, x_pos, round(app.Slice_slider.Value), 1:app.M0start_no)); squeeze(app.Stack(y_pos, x_pos, round(app.Slice_slider.Value), app.CESTdata(2).Mz_stack(i).Mz_ind)); squeeze(app.Stack(y_pos, x_pos, round(app.Slice_slider.Value), app.Stack_dim(4)-app.M0end_no+1:app.Stack_dim(4)))];
                            end
                            CT= cbrewer('qual', 'Paired', (numel(app.CESTdata(1).diffoffsets)));
                            %axes(app.Dynamic_axis);
                            if M0onoff_checkbox_val == 1
                                % Plot the CEST data

                                plot(app.Dynamic_axis, Full_Stack_offsets, '.-', 'Color', CT(i, :));

                            else

                                plot(app.Dynamic_axis, Full_Stack_offsets, '.-', 'Color', CT(i, :));
                                %axis( app.Dynamic_axis, [app.M0start_no+1 (app.Stack_dim(4)-app.M0end_no+1)/(size(app.CESTdata(1).diffoffsets, 2)-1)+1  min(Full_Stack_offsets(app.M0start_no:end-app.M0end_no))-35 max(Full_Stack_offsets(app.M0start_no+1:end-app.M0end_no))+5]);
                                xticks(app.Dynamic_axis, [app.M0start_no+1:10:(app.Stack_dim(4)-app.M0end_no+1)/(size(app.CESTdata(1).diffoffsets, 2)-1)+1]);

                            end
                            set(app.Dynamic_axis,'YAxisLocation','right');
                            %                     % Plot the CEST data
                            %                     plot(app.Dynamic_axis, Full_Stack_offsets, '.-');
                            %                     %plot(app.Dynamic_axis, [1:app.M0start_no app.CESTdata(1).Mz_stack(i).Mz_ind app.Stack_dim(4)-app.M0end_no+1:app.Stack_dim(4)], Full_Stack_offsets' ,'.-'); hold on;
                            %                     set(app.Dynamic_axis,'YAxisLocation','right');
                            %                     axes(app.Dynamic_axis);
                            %xlim([1 xulim])
                            if norm_checkbox_val == 1
                                ylabel(app.Dynamic_axis, 'M_{z}/M_{0}');
                            else
                                ylabel(app.Dynamic_axis, 'M_{z}');
                            end
                            xlabel(app.Dynamic_axis,'Dynamic number');

                            hold(app.Dynamic_axis, 'on')
                        end
                        if pixeloverlay_checkbox_val == 0
                            hold( app.Dynamic_axis, 'off')
                        end
                    end
                end
            else
                disp('Cursor is outside bounds of image.');
                return
            end
        end

        % Button pushed function: roi_pushbutton
        function roi_pushbuttonButtonPushed(app, event)
            % --- Executes on button press in roi_pushbutton.


            % hObject    handle to roi_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)

            app.noOfROIs = app.roisno_edit.Value;
            freeshape_val = app.FreeButton.Value;
            polyshape_val = app.PolyButton.Value;
            ellipseshape_val = app.ElipseButton.Value;
            regiongrowing_val = app.FreeGrowingButton.Value;

            if (polyshape_val == 1)
                SegmentationMethod = 'manual';
                shape = 'poly';
            elseif (ellipseshape_val == 1)
                SegmentationMethod = 'manual';
                shape = 'ellipse';
            elseif (freeshape_val == 1)
                SegmentationMethod = 'manual';
                shape = 'free';
            elseif (regiongrowing_val == 1)
                SegmentationMethod = 'automatic';
                shape = [];
            end


            for sl = 1:app.CESTdata(1).stack_dim(3)
                [Mask, ROIdef] = createROI(app.CESTdata(1).M0_stack_full(:, :, sl), SegmentationMethod, sl, app.noOfROIs, shape);
                app.Mask_full(:, :, sl) = Mask;
                app.ROIdef_full(:, :, sl, :) = ROIdef;
            end


            figure(10)
            for sl = 1:app.CESTdata(1).stack_dim(3)
                subplot(2, round(app.CESTdata(1).stack_dim(3)/2), sl)
                imagesc(app.Mask_full(:, :, sl));
                axis off;
                axis equal;
                title(sprintf('ROI(s) in slice %d', sl));
                colormap gray;
                %CT = cbrewer('seq', 'YlGnBu', 9);
                %colormap(CT)


                assignin('base', 'Mask_full', app.Mask_full);
                assignin('base', 'ROIdef_full', app.ROIdef_full);
                title_s = strcat(sprintf('ROI(s) in slice %d', sl), '_',string(datetime('now')));
                saveas(gcf, fullfile(app.saved_dir, title_s), 'fig');

            end
            UpdateSliders_Callback(app, event);
        end

        % Button pushed function: threshold_run
        function threshold_runButtonPushed(app, event)
            % --- Executes on button press in sam_pushbutton.


            % hObject    handle to sam_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            %app.prerangelb_edit = app.prerange_start.Value
            %app.prerangeub_edit = app.prerange_end.Value
            %app.postrangelb_edit = app.postrange_start.Value
            %app.postrangeub_edit = app.postrange_end.Value
            %app.threshold_edit = app.contrast_thresh.Value
            %app.Mzdisp_val =
            %app.MzM0disp_val =
            %app.MTRdisp_val =
            %app.CESThistogramdisp_val =

            pre_lb = app.prerange_start.Value;
            pre_ub = app.prerange_end.Value;
            post_lb = app.postrange_start.Value;
            post_ub = app.postrange_end.Value;
            threshold = app.contrast_thresh.Value;


            % Average multiple offfsets to obtain offset1 and offset2 for the
            % ratiometric analysis
            for ke = 1:2
                try
                    Mz_stack = app.CESTdata(ke).Mz_stack_mocorr_sort_full;
                catch
                    Mz_stack = app.CESTdata(ke).Mz_stack_norm;
                end

                if size(app.CESTdata(2).diffoffsets, 2) > 3
                    app.CESTdata = averageMOffsets(app.CESTdata, Mz_stack, ke);
                else
                    app.CESTdata(ke).Mz_offset1 = app.CESTdata(ke).Mz_stack_mocorr(1).OffsetStack;
                    app.CESTdata(ke).Mz_offset2 = app.CESTdata(ke).Mz_stack_mocorr(2).OffsetStack;
                end
            end


            % Calculate average pre-injection image
            Mpre_offset1 = app.CESTdata(2).Mz_offset1;
            Mpre_offset2 = app.CESTdata(2).Mz_offset2;
            %Mpre_offset1 =  reshape(Mpre_offset1, size(Mpre_offset1, 1, []), size(Mpre_offset1, 2, []), 1, size(Mpre_offset1, 3, []));
            %  Mpost_offset1 = app.CESTdata(2).Mz_offset1;
            %  Mpost_offset2 = app.CESTdata(2).Mz_offset2;
            % Calculate average pre-injection image

            %             try
            mean_Mpre_offset1 = mean(Mpre_offset1(:, :, :, (pre_lb-app.CESTdata(2).M0start_no):(pre_ub-app.CESTdata(1).M0start_no)), 4);
            mean_Mpre_offset2 = mean(Mpre_offset2(:, :, :, (pre_lb-app.CESTdata(2).M0start_no):(pre_ub-app.CESTdata(1).M0start_no)), 4);
            % Get the post-injection images in the specified range
            Mpost_offset1 = app.CESTdata(2).Mz_offset1(:, :, :, (post_lb-app.CESTdata(2).M0start_no):(post_ub-app.CESTdata(2).M0start_no));
            Mpost_offset2 = app.CESTdata(2).Mz_offset2(:, :, :, (post_lb-app.CESTdata(2).M0start_no):(post_ub-app.CESTdata(2).M0start_no));


            %
            %             catch
            %                 mean_Mpre_offset1 = mean(Mpre_offset1(:, :, :),3);
            %                 mean_Mpre_offset2 = mean(Mpre_offset2(:, :, :),3);
            %                  % Get the post-injection images in the specified range
            %             Mpost_offset1 = app.CESTdata(2).Mz_offset1(:, :, :);
            %             Mpost_offset2 = app.CESTdata(2).Mz_offset2(:, :, :);
            %             end


            % Dynamic signal enhancement analysis
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % Calculate relative signal (pre-post) and percentage signal enhancement
            % (PSE)
            for i = 1:size(Mpost_offset1, 4)

                diff_offset1(:, :, :, i) = mean_Mpre_offset1 -Mpost_offset1(:, :, :, i);  % relative signal at offset 1
                diff_offset2(:, :, :, i) = mean_Mpre_offset2- Mpost_offset2(:, :, :, i);  % relative signal at offset 2

                SE_offset1(:, :, :, i) = 100.*diff_offset1(:, :, :, i)./(mean_Mpre_offset1); % normalized relative signal at offset 1
                SE_offset2(:, :, :, i) = 100.*diff_offset2(:, :, :, i)./(mean_Mpre_offset2); % normalized relative signal at offset 2

            end
            for sl = 1:app.CESTdata(1).stack_dim (3)
                for kx = 1:app.CESTdata(1).stack_dim(1)
                    for ky = 1:app.CESTdata(1).stack_dim(2)
                        if (max(SE_offset1(kx, ky, sl, :)) < threshold/100) == 1
                            SE_offset1(kx, ky, sl, :) =  NaN;
                        end

                        if (max(SE_offset2(kx, ky, sl, :)) < threshold/100) == 1
                            SE_offset2(kx, ky, sl, :) =  NaN;
                        end

                    end
                end
            end

            ROINaN  = double(app.ROIdef_full); % Replace zeros with NaNs in ROIdef_full
            ROINaN(ROINaN==0) = NaN;

            MaskNaN = double(app.Mask_full);
            MaskNaN(MaskNaN==0) = NaN;


            time_vector = round(2*app.CESTdata(2).TR/1000.*(1:(post_ub-post_lb+1))); % calculate time vector


            for i = 1 : size(app.ROIdef_full, 3)
                diff_offset1_roi(i, :) = mean(diff_offset1.*ROINaN(:, :, i), [1 2], 'omitnan' );
                diff_offset2_roi(i, :) = mean(diff_offset2.*ROINaN(:, :, i), [1 2], 'omitnan');

                SE_offset1_roi(i, :) = mean(SE_offset1.*ROINaN(:, :, i), [1 2], 'omitnan');
                SE_offset2_roi(i, :) = mean(SE_offset2.*ROINaN(:, :, i), [1 2 ], 'omitnan');
            end

            if app.sigenhanc_val.Value == 1

                for i = 1 : size(app.ROIdef_full, 3)
                    figure(102);
                    CT = cbrewer('qual', 'Paired', 9);

                    % Plot the signal enhancement curves at offset 1
                    set(gcf,'Name','Signal Enhancement at Offset 1', 'NumberTitle','off')
                    plot(time_vector, SE_offset1_roi(i, :), 'Color', [CT(i+2,:)], 'LineWidth', 2);
                    hold on;
                    grid on;
                    title([sprintf('Signal Enhancement at %.1f ppm', app.CESTdata(2).diffoffsets(1)./app.CESTdata(2).B0_MHz)]);
                    ylabel('Signal Enhancement %')
                    xlabel('Time after injection [sec]')
                    legend('Signal, ROI#1', 'Signal, ROI#2');
                    title_s = strcat([sprintf('Signal Enhancement at %.1f ppm', app.CESTdata(2).diffoffsets(1)./app.CESTdata(2).B0_MHz)], string(datetime('now')));
                    saveas(gcf, fullfile(app.saved_dir, title_s ), 'fig');
                    % Plot the signal enhancement curves at offset 2
                    figure(103);
                    set(gcf,'Name','Signal Enhancement at Offset 2', 'NumberTitle','off')
                    plot(time_vector, SE_offset2_roi(i, :),  'Color', [CT(i+2,:)], 'LineWidth', 2);
                    hold on;
                    grid on;
                    title([sprintf('Signal Enhancement at %.1f ppm', app.CESTdata(2).diffoffsets(2)./app.CESTdata(2).B0_MHz)]);
                    ylabel('Signal Enhancement %')
                    xlabel('Time after injection [sec]')
                    legend('Signal, ROI#1', 'Signal, ROI#2');
                    title_s = strcat([sprintf('Signal Enhancement at %.1f ppm', app.CESTdata(2).diffoffsets(2)./app.CESTdata(2).B0_MHz)], string(datetime('now')));
                    saveas(gcf, fullfile(app.saved_dir, title_s ), 'fig');
                end
            end


            if exist('time_vector', 'var')
                assignin('base', 'time_vector', time_vector);
            end
            if exist('SE_offset1_roi', 'var')
                assignin('base', 'SE_offset1_roi', SE_offset1_roi);
            end
            % CEST analysis
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            Mpost_offset1 = app.CESTdata(2).Mz_offset1(:, :, :, (post_lb-app.CESTdata(2).M0start_no):(post_ub-app.CESTdata(2).M0start_no));
            Mpost_offset2 = app.CESTdata(2).Mz_offset2(:, :, :, (post_lb-app.CESTdata(2).M0start_no):(post_ub-app.CESTdata(2).M0start_no));


            %if (post_lb > app.CESTdata(2).M0start_no - 1 && post_ub < size(diff_offset1, 4) + app.CESTdata(2).M0start_no)

            if app.average_val.Value == 1 % average the images in the specified post-injection range
                mean_Mpost_offset1 = mean(Mpost_offset1, 4);
                mean_Mpost_offset2 = mean(Mpost_offset2, 4);
            else % find a minimum in the specified post-injection range and average the images in the chosen min range
                for sl = 1:app.CESTdata(1).stack_dim (3)
                    for  i = 1 : size(app.ROIdef_full, 1)
                        for  j = 1 : size(app.ROIdef_full, 1)
                            [PER_offset1(i, j), min_Mpre_offset1_ind(i, j)]  = min(Mpost_offset1(i, j, sl, :), [], 4);
                            [PER_offset2(i, j), min_Mpre_offset2_ind(i, j)]  = min(Mpost_offset2(i, j, sl, :), [], 4);
                            mean_Mpost_offset1(i, j, sl) =  mean(app.CESTdata(2).Mz_offset1(i, j, sl, post_lb-app.CESTdata(2).M0start_no + min_Mpre_offset1_ind(i, j, sl) - 1 - app.minrange.Value: post_lb-app.CESTdata(2).M0start_no + min_Mpre_offset1_ind(i, j, sl) - 1 + app.minrange.Value), 4);
                            mean_Mpost_offset2(i, j, sl) =  mean(app.CESTdata(2).Mz_offset2(i, j, sl, post_lb-app.CESTdata(2).M0start_no + min_Mpre_offset2_ind(i, j, sl)-1 - app.minrange.Value: post_lb-app.CESTdata(2).M0start_no +min_Mpre_offset2_ind(i, j, sl) -1 + app.minrange.Value), 4);

                        end
                    end
                end
            end

            % Get difference in Mz
            mean_diff_offset1 = (mean_Mpre_offset1 - mean_Mpost_offset1).*MaskNaN;
            mean_diff_offset2 = (mean_Mpre_offset2 - mean_Mpost_offset2).*MaskNaN;

            % Get Mz/M0
            mean_norm_offset_post1 = (mean_Mpost_offset1./app.CESTdata(2).M0norm_post).*MaskNaN;
            mean_norm_offset_post2 = (mean_Mpost_offset2./app.CESTdata(2).M0norm_post).*MaskNaN;

            mean_norm_offset_pre1 = (mean_Mpre_offset1./app.CESTdata(1).M0norm_pre).*MaskNaN;
            mean_norm_offset_pre2 = (mean_Mpre_offset2./app.CESTdata(1).M0norm_pre).*MaskNaN;


            mean_norm_offset1 = mean_norm_offset_post1;
            mean_norm_offset2 = mean_norm_offset_post2;

            % MTR difference
            mean_MTR_offset1 = (1 - mean_norm_offset_post1) - (1 - mean_norm_offset_pre1);
            mean_MTR_offset2 = (1 - mean_norm_offset_post2) - (1 - mean_norm_offset_pre2);


            mean_MTR_offset1(mean_MTR_offset1<0) = NaN;
            mean_MTR_offset2(mean_MTR_offset2<0) = NaN;


            % Find minimal and maximal contrast
            for sl = 1:app.CESTdata(1).stack_dim(3)
                max_offset1(sl) = max(mean_diff_offset1(:, :, sl),[],'all', 'omitnan');
                max_offset2(sl) = max(mean_diff_offset2(:, :, sl),[],'all', 'omitnan');

                min_offset1(sl) = min(mean_diff_offset1(:, :, sl),[],'all', 'omitnan');
                min_offset2(sl) = min(mean_diff_offset2(:, :, sl),[],'all', 'omitnan');

                minmax_offset12(sl) = min(max_offset1(sl), max_offset2(sl));
            end


            app.Mask_full_threshold = app.Mask_full;
            app.ROIdef_full_threshold = app.ROIdef_full;

            % Select pixels above defined threshold
            for sl = 1:app.CESTdata(1).stack_dim (3)

                for kx = 1:app.CESTdata(1).stack_dim(1)
                    for ky = 1:app.CESTdata(1).stack_dim(2)
                        if (mean_diff_offset1(kx, ky, sl) > threshold/100*minmax_offset12(sl)) == 1 && (mean_diff_offset2(kx, ky, sl) > threshold/100*minmax_offset12(sl)) == 1
                            app.Mask_full_threshold(kx, ky, sl) =  1;
                        end
                    end
                end

                for j = 1:size(app.ROIdef_full, 4)
                    app.ROIdef_full_threshold(:, :, sl, j) = double(app.ROIdef_full(:, :, sl, j)).*double(app.Mask_full_threshold(:, :, sl));
                end

            end


            if app.Mzdisp_val.Value == 1
                Disp_map_offset1 = mean_diff_offset1.*app.Mask_full_threshold;
                Disp_map_offset2 = mean_diff_offset2.*app.Mask_full_threshold;
                max_lim = max(max(Disp_map_offset1,[],'all'), max(Disp_map_offset2,[],'all'));
                min_lim = min(min(Disp_map_offset1,[],'all'), min(Disp_map_offset2,[],'all'));
                clabel = 'Mz';
                figure('Name','Mz images','NumberTitle','off');

                title_s = strcat('Mz images', string(datetime('now')));
                createPlots(Disp_map_offset1, Disp_map_offset2, app.CESTdata, min_lim, max_lim, clabel);
                saveas(gcf, fullfile(app.saved_dir, title_s ), 'fig');
            end

            if app.MzM0disp_val.Value == 1
                Disp_map_offset1 = mean_norm_offset1.*app.Mask_full_threshold;
                Disp_map_offset2 = mean_norm_offset2.*app.Mask_full_threshold;
                max_lim = max(max(Disp_map_offset1,[],'all'), max(Disp_map_offset2,[],'all'));
                min_lim = min(min(Disp_map_offset1,[],'all'), min(Disp_map_offset2,[],'all'));
                clabel = 'Mz/M0';
                figure('Name','Mz/M0 images','NumberTitle','off');
                title_s = strcat('Mz_div_M0 images', string(datetime('now')));
                createPlots(Disp_map_offset1, Disp_map_offset2, app.CESTdata, min_lim, max_lim, clabel);
                saveas(gcf, fullfile(app.saved_dir, title_s ), 'fig');
            end

            if app.MTRdisp_val.Value == 1
                Disp_map_offset1 = mean_MTR_offset1.*app.Mask_full_threshold;
                Disp_map_offset2 = mean_MTR_offset2.*app.Mask_full_threshold;
                max_lim = max(max(Disp_map_offset1,[],'all'), max(Disp_map_offset2,[],'all'));
                min_lim = min(min(Disp_map_offset1,[],'all'), min(Disp_map_offset2,[],'all'));
                clabel = 'MTR';
                figure('Name','MTR images','NumberTitle','off');
                title_s= strcat('MTR images', string(datetime('now')));
                createPlots(Disp_map_offset1, Disp_map_offset2, app.CESTdata, min_lim, max_lim, clabel);
                saveas(gcf, fullfile(app.saved_dir, title_s ), 'fig');
            end

            if app.CESThistogramdisp_val.Value == 1
                Disp_map_offset1 = 100.*mean_norm_offset1.*app.Mask_full_threshold;
                Disp_map_offset2 = 100.*mean_norm_offset2.*app.Mask_full_threshold;
                max_lim = max(max(Disp_map_offset1,[],'all'), max(Disp_map_offset2,[],'all'));
                min_lim = min(min(Disp_map_offset1,[],'all'), min(Disp_map_offset2,[],'all'));

                % Plot contrast histograms
                figure('Name','Contrast histogram, Offset 1','NumberTitle','off');

                for sl = 1:app.CESTdata(1).stack_dim(3)
                    for nr = 1:size(app.ROIdef_full_threshold, 4)
                        Contrast1_roi = Disp_map_offset1(:, :, sl).*app.ROIdef_full_threshold(:, :, sl, nr);
                        subplot(2, round(app.CESTdata(1).stack_dim(3)/2), sl)
                        histogram(Contrast1_roi(~isnan(Contrast1_roi)), 20, 'BinWidth', 2, 'BinLimits', [min_lim max_lim], 'Normalization', 'probability')
                        ylim([0 0.45])
                        ytix = get(gca, 'YTick');
                        set(gca, 'YTick',ytix, 'YTickLabel', ytix*100,  'FontSize', 16, 'LineWidth', 2, 'XLimitMethod', 'tight', 'YLimitMethod','tight');
                        title(sprintf('Slice %d', sl));
                        xlabel('Contrast %')
                        ylabel('% of Pixels')
                        hold on;
                        title_s= strcat('Contrast histogram, Offset 1', string(datetime('now')));
                        saveas(gcf, fullfile(app.saved_dir, title_s ), 'fig');
                    end
                end

                figure('Name','Contrast histogram, Offset 2','NumberTitle','off');
                for sl = 1:app.CESTdata(1).stack_dim(3)
                    for nr = 1:size(app.ROIdef_full_threshold, 4)
                        Contrast2_roi = Disp_map_offset2(:, :, sl).*app.ROIdef_full_threshold(:, :, sl, nr);
                        subplot(2, round(app.CESTdata(1).stack_dim(3)/2), sl)
                        histogram(Contrast2_roi(~isnan(Contrast2_roi)), 20, 'BinWidth', 2, 'BinLimits', [min_lim max_lim], 'Normalization', 'probability')
                        ylim([0 0.45])
                        ytix = get(gca, 'YTick');
                        set(gca, 'YTick',ytix, 'YTickLabel', ytix*100,  'FontSize', 16, 'LineWidth', 2, 'XLimitMethod', 'tight', 'YLimitMethod','tight');
                        title(sprintf('Slice %d', sl));
                        xlabel('Contrast %')
                        ylabel('% of Pixels')
                        hold on;
                        title_s= strcat('Contrast histogram, Offset 2', string(datetime('now')));
                        saveas(gcf, fullfile(app.saved_dir, title_s), 'fig');
                    end
                end

            end

            app.CESTresults.mean_diff_offset1 = mean_diff_offset1.*app.Mask_full_threshold;
            app.CESTresults.mean_diff_offset2 = mean_diff_offset2.*app.Mask_full_threshold;

            app.CESTresults.mean_norm_offset1 = mean_norm_offset1.*app.Mask_full_threshold;
            app.CESTresults.mean_norm_offset2 = mean_norm_offset2.*app.Mask_full_threshold;

            app.CESTresults.mean_MTR_offset1 = mean_MTR_offset1.*app.Mask_full_threshold;
            app.CESTresults.mean_MTR_offset2 = mean_MTR_offset2.*app.Mask_full_threshold;

            CESTdata = app.CESTdata;
            CESTresults = app.CESTresults;
            assignin('base', 'CESTdata', CESTdata);
            assignin('base', 'CESTresults', CESTresults);

        end

        % Button pushed function: threshold_run_2
        function threshold_run_2ButtonPushed(app, event)
            % hObject    handle to pHmap_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)

            inverseMTR_radiobutton_val = get(app.InverseCheckBox, 'value');
            MTRratiodisp_radiobutton_val = get(app.MTRratioButton_2, 'value');
            pHmapdisp_radiobutton_val = get(app.pHmapButton_2, 'value');
            pHhistogram_radiobutton_val = get(app.pHHistogramButton_2, 'value');

            mean_MTR_offset1 = app.CESTresults.mean_MTR_offset1;
            mean_MTR_offset2 = app.CESTresults.mean_MTR_offset2;


            % Calculate the MTR ratio
            if inverseMTR_radiobutton_val == 1
                % MTRratio = ((1 - mean_MTR_offset1).*mean_MTR_offset2)./((1 - mean_MTR_offset2).*mean_MTR_offset1);
                MTRratio = ((1 - mean_MTR_offset2).*mean_MTR_offset1)./((1 - mean_MTR_offset1).*mean_MTR_offset2);
            else
                MTRratio = mean_MTR_offset1./mean_MTR_offset2;
                %MTRratio = mean_MTR_offset2./mean_MTR_offset1;
            end

            % Get the coefficients of the calibration curve
            p1 = app.p1EditField.Value;
            p2 = app.p2EditField.Value;
            p3 = app.p3EditField.Value;
            p4 = app.p4EditField.Value;

            % Calculate the pH maps
            if p4 == 0
                pHmap_all =  p1*MTRratio.^2 + p2*MTRratio + p3;
            else
                pHmap_all =  p1*MTRratio.^3 + p2*MTRratio.^2 + p3*MTRratio + p4;
            end

            pHmap = pHmap_all;
            pHmap(pHmap < 5.0) = NaN;
            pHmap(pHmap > 8.5) = NaN;


            if MTRratiodisp_radiobutton_val == 1
                max_lim = max(MTRratio,[],'all');
                min_lim = min(MTRratio,[],'all');
                clabel = 'MTRratio';
                figure('Name','MTRratio maps','NumberTitle','off');
                createpHmaps(MTRratio, app.CESTdata, min_lim, max_lim, clabel);
                title_s= strcat('MTRratio maps', string(datetime('now')));
                saveas(gcf, fullfile(app.saved_dir, title_s), 'fig');
            end


            % Plot the pH maps
            if pHmapdisp_radiobutton_val == 1
                max_lim = max(pHmap,[],'all');
                min_lim = min(pHmap,[],'all');
                clabel = 'pH';
                figure('Name','pH maps','NumberTitle','off');
                createpHmaps(pHmap, app.CESTdata, min_lim, max_lim, clabel);
                title_s= strcat('pH maps', string(datetime('now')));
                saveas(gcf, fullfile(app.saved_dir, title_s), 'fig');
            end

            if pHhistogram_radiobutton_val == 1
                figure('Name','pH histogram','NumberTitle','off');
                min_lim = min(pHmap,[],'all');
                % Plot pH histogram
                for sl = 1:app.CESTdata(1).stack_dim(3)
                    for nr = 1:size(app.ROIdef_full_threshold, 4)
                        pHmap_roi = pHmap(:, :, sl).*app.ROIdef_full_threshold(:, :, sl, nr);
                        subplot(2, round(app.CESTdata(1).stack_dim(3)/2), sl)
                        histogram(pHmap_roi(~isnan(pHmap_roi)), 20, 'BinWidth', 0.1, 'BinLimits', [min_lim 8], 'Normalization', 'probability')
                        ylim([0 0.45])
                        ytix = get(gca, 'YTick');
                        set(gca, 'YTick',ytix, 'YTickLabel', ytix*100,  'FontSize', 16, 'LineWidth', 2, 'DataAspectRatio', [1 0.33 1], 'XLimitMethod', 'tight', 'YLimitMethod','tight');
                        title(sprintf('Slice %d', sl));
                        xlabel('pH')
                        ylabel('% of Pixels')
                        hold on;
                    end
                end

                title_s= strcat('pH histogram', string(datetime('now')));
                saveas(gcf, fullfile(app.saved_dir, title_s), 'fig');
            end

            %              if app.sigenhanc_val.Value == 1
            %
            %       for i = 1 : size(app.ROIdef_full, 3)
            %         figure(102);
            %         CT = cbrewer('qual', 'Paired', 9);
            %
            %         % Plot the signal enhancement curves at offset 1
            %         set(gcf,'Name','Signal Enhancement at Offset 1', 'NumberTitle','off')
            %         plot(time_vector, SE_offset1_roi(i, :), 'Color', [CT(i+2,:)], 'LineWidth', 2);
            %         hold on;
            %         grid on;
            %         title([sprintf('Signal Enhancement at %.1f ppm', app.CESTdata(2).diffoffsets(1)./app.CESTdata(2).B0_MHz)]);
            %         ylabel('Signal Enhancement %')
            %         xlabel('Time after injection [sec]')
            %         legend('Signal, ROI#1', 'Signal, ROI#2');
            %         title_s = strcat([sprintf('Signal Enhancement at %.1f ppm', app.CESTdata(2).diffoffsets(1)./app.CESTdata(2).B0_MHz)], string(datetime('now')));
            %         saveas(gcf, fullfile(app.saved_dir, title_s), 'fig');
            %         % Plot the signal enhancement curves at offset 2
            %         figure(103);
            %         set(gcf,'Name','Signal Enhancement at Offset 2', 'NumberTitle','off')
            %         plot(time_vector, SE_offset2_roi(i, :),  'Color', [CT(i+2,:)], 'LineWidth', 2);
            %         hold on;
            %         grid on;
            %         title([sprintf('Signal Enhancement at %.1f ppm', app.CESTdata(2).diffoffsets(2)./app.CESTdata(2).B0_MHz)]);
            %         ylabel('Signal Enhancement %')
            %         xlabel('Time after injection [sec]')
            %         legend('Signal, ROI#1', 'Signal, ROI#2');
            %         title_s = strcat([sprintf('Signal Enhancement at %.1f ppm', app.CESTdata(2).diffoffsets(2)./app.CESTdata(2).B0_MHz)], string(datetime('now')));
            %         saveas(gcf, fullfile(app.saved_dir, title_s), 'fig');
            %     end
            % end


            app.CESTresults.pHmap = pHmap;
            app.CESTresults.MTRratio = MTRratio;


            assignin('base', 'CESTresults', app.CESTresults);


        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            % Save results
            %             app.Stack
            %                              app.WASSRdata
            %                               app.CESTdata
            %                               app.CESTresults
            %              app.offset_Hz_full
            %                             app.M0start_no
            %                               app.M0end_no
            %                              app.Stack_dim
            %                              app.I_current
            %                          app.ROIdef_full
            %                              app.Mask_full
            %                               app.noOfROIs
            %                    app.Mask_full_threshold
            %                  app.ROIdef_full_threshold
            %                         app.hist_mask_full
            %
            %                        app.saved_file_path
            %                           app.saved_folder
            %                              app.saved_dir
            %                                 app.filenm
            %       pre_lb_saved = app.prerange_start.Value;
            %             pre_ub_saved = app.prerange_end.Value;
            %             post_lb_saved = app.postrange_start.Value;
            %             post_ub_saved = app.postrange_end.Value;
            %             threshold_saved= app.contrast_thresh.Value;
            %             app.Heq_threshold.Value
            %                             Stack_saved = app.Stack;
            %                              WASSRdata_saved = app.WASSRdata;
            %                               CESTdata_saved = app.CESTdata;
            %                               CESTresults_saved = app.CESTresults;
            %              offset_Hz_full_saved = app.offset_Hz_full;
            %                             M0start_no_saved = app.M0start_no;
            %                               M0end_no_saved = app.M0end_no;
            %                              Stack_dim_saved = app.I_current;
            %                              I_current_saved = app.I_current;
            ROIdef_full_saved = app.ROIdef_full;
            Mask_full_saved = app.Mask_full;
            %                               noOfROIs_saved = app.noOfROIs;
            %                    Mask_full_threshold_saved = app.Mask_full_threshold;
            %                  ROIdef_full_threshold_saved = app.ROIdef_full_threshold;
            %                         hist_mask_full_saved = app.hist_mask_full;
            %                            saved_file_path_saved = app.saved_file_path;
            %                           saved_folder_saved = app.saved_folder;
            %                            saved_dir_saved =   app.saved_dir;
            %                         filenm_saved  = app.filenm;
            %
            %


            %these are crucial for loading the base of the previous work
            Pre_injection_field_Value_saved = app.Pre_injection_field.Value ;
            post_injection_field_Value_saved = app.post_injection_field.Value ;
            medianfilterCheckBox_Value_saved = app.medianfilterCheckBox.Value ;
            FolderPathEditField_Value_saved = app.FolderPathEditField.Value;
            WASSRfield_Value_saved =  app.WASSRfield.Value;
            NormalizationDropDown_Value_saved = app.NormalizationDropDown.Value;

            listofvars= {'NormalizationDropDown_Value_saved', 'Pre_injection_field_Value_saved' ,  'post_injection_field_Value_saved', 'medianfilterCheckBox_Value_saved' ,'FolderPathEditField_Value_saved','WASSRfield_Value_saved', 'ROIdef_full_saved', 'Mask_full_saved'};
            xi = fullfile(app.saved_dir, app.filenm);
            app.NOTESTextArea.Value = xi;
            save(xi,listofvars{:})
            %evalin(temp, ['save(''', filenm ''')']);
            %movefile(filenm, app.saved_file_path);
            disp('Data saved succesfully')
        end

        % Button pushed function: SAVENOTESButton
        function SAVENOTESButtonPushed(app, event)
            value = app.NOTESTextArea.Value;  % Value entered in textArea
            f=fopen(fullfile(app.saved_dir, strcat('notes_',string(datetime('now', 'Format', 'yyyy-MM-dd''T''HH-mm-ss-SSS')),'.txt')),'w');
            formatSpec= "%s";
            for i =1:length(value)
                fprintf(f,formatSpec,value{i});
            end
        end

        % Button pushed function: ApplyThresholdButton
        function ApplyThresholdButtonPushed(app, event)
            threshold= app.Heq_threshold.Value;
            %[xdim, ydim]=size(app.I_current);
            [xdim, ydim, ~, zdim]=size(app.Stack(:, :, round(app.Slice_slider.Value), :));
            app.ROIdef_full; %phantom mask
            a1=zeros(xdim,ydim,zdim);
            a_R_2=zeros(xdim,ydim);
            a_R_2_thresh=zeros(xdim,ydim);
            well_number=size(app.ROIdef_full,3);
            xvals= 1:zdim;
            %well_number=size(app.Mask_full,3);
            % commented bellow is the intensity map
            %figure;
            %imagesc(app.I_current);
            for z=1:zdim
                for z1  = 1:well_number
                    [row,col] = find(app.ROIdef_full(:,:,round(app.Slice_slider.Value),z1)); %might be 1 instead of round(app.Slice_slider.Value)
                    for m=1:length(row)
                        truval = app.Stack(row(m), col(m), round(app.Slice_slider.Value), z);
                        pix_data_table(m,z1,z)=truval;
                        a1(row(m),col(m),z) = truval;
                    end
                end
            end
            for z1=1:well_number
                for m=1:length(row)

                    polyfit_y=squeeze([pix_data_table(m,z1,:)]);
                    idx = isnan(polyfit_y);
                    x_input=transpose(xvals(not(idx)));
                    y_input=polyfit_y(not(idx));
                    %start of r sqaured finding algorthim for each pixel

                    r= corrcoef(x_input, y_input);

                    R_2=r(2)^2;
                    %squaring the r values calculated to get the r squared
                    r2(m,z1)=R_2;
                    % this table is to orginizes the r squared values generated into a table to
                    % be put into the r squared map accurately

                end
            end
            for z1  = 1:well_number
                [row,col] = find(app.ROIdef_full(:,:,round(app.Slice_slider.Value),z1)); %might be 1 instead of round(app.Slice_slider.Value)
                for m = 1:length(row)
                    a_R_2(row(m),col(m))= r2(m,z1)*100;


                    if r2(m,z1) >= threshold/100

                        a_R_2_thresh(row(m),col(m))= r2(m,z1)*100;
                    else
                        a_R_2_thresh(row(m),col(m)) = 0;
                    end
                    % matching the R squred value calculated to its given pixel in its given
                    % well and putting it in a picture where background pixels(ones that are
                    % not in the designated well will already be equal zero
                end
            end


            figure('Name', sprintf("R^2 Without Threshold"), 'NumberTitle','off');
            title(sprintf("R^2 Without Threshold"));
            imagesc(a_R_2);
            saveas(gcf, fullfile(app.saved_dir, strcat(sprintf("R^2 Without Threshold"), string(datetime('now')))), 'fig');
            t_temp= "R^2 With " + string(threshold);
            figure('Name', sprintf(t_temp + " Threshold"), 'NumberTitle','off');
            title([t_temp, "Threshold"]);
            imagesc(a_R_2_thresh);

            imagesc(app.R2_map ,a_R_2_thresh);
            axis(app.R2_map ,  'off');
            axis(app.R2_map ,  'equal');
            saveas(gcf, fullfile(app.saved_dir, sprintf(t_temp + " Threshold" + string(datetime('now')))), 'fig');
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)

            %             app.Stack
            %                              app.WASSRdata
            %                               app.CESTdata
            %                               app.CESTresults
            %              app.offset_Hz_full
            %                             app.M0start_no
            %                               app.M0end_no
            %                              app.Stack_dim
            %                              app.I_current
            %                            app.ROIdef_full
            %                              app.Mask_full
            %                               app.noOfROIs
            %                    app.Mask_full_threshold
            %                  app.ROIdef_full_threshold
            %                         app.hist_mask_full
            %
            %                        app.saved_file_path
            %                           app.saved_folder
            %                              app.saved_dir
            %                                 app.filenm
            [filenm_,pathnm_s] = uigetfile('*.mat');

            app.oldfile = load(fullfile(pathnm_s,filenm_));
            app.Pre_injection_field.Value =  9; %app.oldfile.Pre_injection_field_Value_saved;
            app.post_injection_field.Value = 10; %app.oldfile.post_injection_field_Value_saved;
            app.medianfilterCheckBox.Value = 0; %app.oldfile.medianfilterCheckBox_Value_saved;
            app.FolderPathEditField.Value = pathnm_s; %app.oldfile.FolderPathEditField_Value_saved;
            app.WASSRfield.Value = 7; %app.oldfile.WASSRfield_Value_saved;
            app.NormalizationDropDown.Value =  "Post First M0"; %app.oldfile.NormalizationDropDown_Value_saved;

            if strcmp(app.NormalizationDropDown.Value, 'Pre First M0')
                M0normSel = 'M0pre_start';
            elseif strcmp(app.NormalizationDropDown.Value, 'Pre Last M0')
                M0normSel = 'M0pre_end';
            elseif strcmp(app.NormalizationDropDown.Value, 'Post First M0')
                M0normSel = 'M0post_start';
            elseif strcmp(app.NormalizationDropDown.Value, 'Post Last M0')
                M0normSel = 'M0post_end';
            end

            exp_no= {app.Pre_injection_field.Value, app.post_injection_field.Value};

            % Load CEST data
            if ~isempty(app.Pre_injection_field.Value) || ~isempty(app.post_injection_field.Value)
                app.CESTdata = loadCESTdata(exp_no, app.FolderPathEditField.Value, app.medianfilterCheckBox.Value);

                CESTdata = app.CESTdata;
                disp('CEST Data loaded');

            else
                disp('CEST Data not loaded');
            end
            % Load WASSR data
            if ~isempty(app.WASSRfield)
                app.WASSRdata = loadWASSRdata(app.WASSRfield.Value, app.FolderPathEditField.Value, app.medianfilterCheckBox.Value);

                assignin('base', 'WASSRdata', app.WASSRdata);
            else
                disp('WASSR Data not loaded');
            end
            % % Load T2 data
            % if ~isempty(app.anatomic_exp_no)
            %     app.T2data = loadT2data(app.anatomic_exp_no, app.exp_path, app.filter_checkbox);
            %     T2data = app.T2data;
            %     assignin('base', 'T2data', T2data);
            % else
            %   disp('T2w data not loaded');
            % end

            % maps_popupmenu_Callback(hObject, eventdata, app);

            % Show pre-injection stack data
            % app.Stack = app.CESTdata(1).Mz_stack_full;

            try
                app.Stack = app.CESTdata(1).Mz_stack_full;  % initial stack is the pre-injection stac
                app.offset_Hz_full.Value = app.CESTdata(1).offset_Hz_full;
                app.M0start_no.Value = app.CESTdata(1).M0start_no;
                app.M0end_no.Value = app.CESTdata(1).M0end_no;
                app.Stack_dim.Value = app.CESTdata(1).stack_dim;
            catch
                app.Stack = app.WASSRdata.Mz_stack_full;
            end


            % set(app.Dynamic_slider, 'Max', size(app.Stack, 4))

            app.Dynamic_slider.Limits(2) = size(app.Stack, 4) ;
            % set(app.Dynamic_slider, 'SliderStep', [1/(size(app.Stack, 4) - 1) 1/(size(app.Stack, 4) - 1)]);
            app.Dynamic_slider.MajorTicks = [1/(size(app.Stack, 4) - 1) 1/(size(app.Stack, 4) - 1)];
            axes(app.Image_view);

            if round(app.Slice_slider.Value) <= size(app.Stack, 3) && round(app.Dynamic_slider.Value) <= size(app.Stack, 4)
                imagesc(app.Image_view, app.Stack(:, :, round(app.Slice_slider.Value), round(app.Dynamic_slider.Value))) % initial stack is the pre-injection stack
                colormap(app.Image_view, 'gray') ;
                axis(app.Image_view, 'off') ;
            end
            colormap(app.Image_view, 'gray') ;
            axis(app.Image_view, 'off') ;

            % Normalize CEST data
            if ~isempty(app.Pre_injection_field) || ~isempty(app.post_injection_field)
                app.CESTdata = normalizeCESTdata(app.CESTdata, M0normSel);

                assignin('base', 'CESTdata', app.CESTdata);
            end
            % getMousePositionOnImage (app, event)
            t2= floor(now);
            d2_date = datetime(t2,'ConvertFrom','datenum', 'Format', 'yyyyMM');
            d2_temp= datetime(t2,'ConvertFrom','datenum');
            w = week(d2_temp);
            app.saved_folder = 'results_'+ string(d2_date) + string(w);
            app.saved_file_path = pwd;
            if contains(app.saved_file_path, app.saved_folder) == 1
                app.saved_dir = app.saved_file_path;
            else
                if not(isfolder(app.saved_folder))
                    mkdir(app.saved_folder);

                    app.saved_dir = strcat(app.saved_file_path, '/' , app.saved_folder);


                else
                    app.saved_file_path = pwd;


                    app.saved_dir = strcat(app.saved_file_path, '/' , app.saved_folder);
                end
            end
            [filepath, name, ~] = fileparts(app.saved_file_path);
            app.filenm = sprintf('%s.mat',datetime('now', 'Format', 'yyyy-MM-dd''T''HH-mm-ss-SSS'));
            % save(filenm);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @Image_viewButtonDown, true);
            app.VIEWPanel = uipanel(app.UIFigure);
            app.VIEWPanel.Title = '                                                     VIEW';
            app.CostumizeView = uipanel(app.VIEWPanel);
            app.CostumizeView.Title = 'Costumize the view';
            app.StackDropDownLabel = uilabel(app.CostumizeView);
            app.StackDropDownLabel.Text = 'Stack';
            app.StackDropDown = uidropdown(app.CostumizeView);
            app.StackDropDown.Items = {'Pre-injection', 'Post-injection', 'WASSR', 'T2w', 'Pre-injection mocorr', 'Post-injection mocorr'};
            app.StackDropDown.ValueChangedFcn = createCallbackFcn(app, @StackDropDownValueChanged, true);
            app.StackDropDown.Value = 'Pre-injection';
            app.NormalizationDropDownLabel = uilabel(app.CostumizeView);
            app.NormalizationDropDownLabel.Text = 'Normalization';
            app.NormalizationDropDown = uidropdown(app.CostumizeView);
            app.NormalizationDropDown.Items = {'Pre First M0', 'Pre Last M0', 'Post First M0', 'Post Last M0'};
            app.NormalizationDropDown.Value = 'Post First M0';
            app.TabGroup = uitabgroup(app.VIEWPanel);
            app.IMAGETab = uitab(app.TabGroup);
            app.IMAGETab.Title = 'IMAGE';
            app.Image_view = uiaxes(app.IMAGETab);
            zlabel(app.Image_view, 'Z')
            app.ROIMAPTab = uitab(app.TabGroup);
            app.ROIMAPTab.Title = 'ROI MAP';
            app.ROI_map = uiaxes(app.ROIMAPTab);
            zlabel(app.ROI_map, 'Z')
            app.R2Tab = uitab(app.TabGroup);
            app.R2Tab.Title = 'R^2';
            app.R2_map = uiaxes(app.R2Tab);
            zlabel(app.R2_map, 'Z')
            app.HistogramEQTab = uitab(app.TabGroup);
            app.HistogramEQTab.Title = 'Histogram EQ';
            app.Histogram_eq = uiaxes(app.HistogramEQTab);
            zlabel(app.Histogram_eq, 'Z')
            app.Slice_slider_Label = uilabel(app.VIEWPanel);
            app.Slice_slider_Label.Text = 'Slice No.';
            app.Dynamic_slider_label = uilabel(app.VIEWPanel);
            app.Dynamic_slider_label.Text = 'Dynamic No.';
            app.Dynamic_slider = uislider(app.VIEWPanel);
            app.Dynamic_slider.Limits = [1 100];
            app.Dynamic_slider.ValueChangedFcn = createCallbackFcn(app, @Dynamic_sliderValueChanged, true);
            app.Dynamic_slider.Value = 1;
            app.Slice_slider = uislider(app.VIEWPanel);
            app.Slice_slider.Limits = [1 100];
            app.Slice_slider.Value = 1;
            app.nbins_label = uilabel(app.VIEWPanel);
            app.nbins_label.Text = 'Bins No.';
            app.nbins = uislider(app.VIEWPanel);
            app.nbins.Limits = [10 150];
            app.nbins.MajorTicks = [10 150];
            app.nbins.MajorTickLabels = {'10', '150'};
            app.nbins.ValueChangedFcn = createCallbackFcn(app, @Dynamic_sliderValueChanged, true);
            app.nbins.MinorTicks = [];
            app.nbins.Value = 10;
            app.PixelAnalyzerPanel = uipanel(app.UIFigure);
            app.PixelAnalyzerPanel.Title = 'Pixel Analyzer';
            app.Dynamic_axis = uiaxes(app.PixelAnalyzerPanel);
            xlabel(app.Dynamic_axis, 'Dynamic number')
            ylabel(app.Dynamic_axis, 'M_z')
            app.CUSTOMIZEPanel = uipanel(app.UIFigure);
            app.CUSTOMIZEPanel.Title = '                                  CUSTOMIZE';
            app.SETUPPanel = uipanel(app.UIFigure);
            app.SETUPPanel.Title = '                             SETUP';
            app.InitiallizingPanel = uipanel(app.SETUPPanel);
            app.InitiallizingPanel.Title = 'Initiallizing ';
            app.FolderPathEditFieldLabel = uilabel(app.InitiallizingPanel);
            app.FolderPathEditFieldLabel.Text = 'Folder Path';
            app.FolderPathEditField = uieditfield(app.InitiallizingPanel, 'text');
            app.LOADButton = uibutton(app.InitiallizingPanel, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.SAVEButton = uibutton(app.InitiallizingPanel, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.ClearAllButton = uibutton(app.InitiallizingPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.CloseButton = uibutton(app.InitiallizingPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.InitiallizingPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.STEP2RUNPanel = uipanel(app.SETUPPanel);
            app.STEP2RUNPanel.Title = 'STEP 2- RUN';
            app.medianfilterCheckBox = uicheckbox(app.STEP2RUNPanel);
            app.medianfilterCheckBox.Text = 'median filter';
            app.Pre_injection_label = uilabel(app.STEP2RUNPanel);
            app.Pre_injection_label.Text = 'Pre injection';
            app.Pre_injection_field = uieditfield(app.STEP2RUNPanel, 'numeric');
            app.Pre_injection_field.Limits = [0 20];
            app.Pre_injection_field.ValueDisplayFormat = '%.0f';
            app.Pre_injection_field.Value = 9;
            app.post_injection_label = uilabel(app.STEP2RUNPanel);
            app.post_injection_label.Text = 'post-injection';
            app.post_injection_field = uieditfield(app.STEP2RUNPanel, 'numeric');
            app.post_injection_field.Limits = [0 40];
            app.post_injection_field.ValueDisplayFormat = '%.0f';
            app.post_injection_field.Value = 10;
            app.WASSRLabel = uilabel(app.STEP2RUNPanel);
            app.WASSRLabel.Text = 'WASSR';
            app.WASSRfield = uieditfield(app.STEP2RUNPanel, 'numeric');
            app.WASSRfield.Limits = [0 40];
            app.WASSRfield.ValueDisplayFormat = '%.0f';
            app.WASSRfield.Value = 7;
            app.Step2_run_button = uibutton(app.STEP2RUNPanel, 'push');
            app.Step2_run_button.ButtonPushedFcn = createCallbackFcn(app, @Step2_run_buttonButtonPushed, true);
            app.Step2_run_button.Text = 'RUN';
            app.Panel = uipanel(app.UIFigure);
            app.NOTESTextAreaLabel = uilabel(app.Panel);
            app.NOTESTextAreaLabel.Text = 'NOTES:  ';
            app.NOTESTextArea = uitextarea(app.Panel);
            app.SAVENOTESButton = uibutton(app.Panel, 'push');
            app.SAVENOTESButton.ButtonPushedFcn = createCallbackFcn(app, @SAVENOTESButtonPushed, true);
            app.SAVENOTESButton.Text = 'SAVE NOTES';
            app.Contrastthreshold_label_2 = uilabel(app.UIFigure);
            app.Contrastthreshold_label_2.Text = 'Mcmahon Lab Image Analyzer App by : Tom Ruvio, Dr. Julia Stabinska, Dr. M. Mcmahon, Dr. A. Gilad ';
            app.SubstractAverageandMaskPanel = uipanel(app.UIFigure);
            app.SubstractAverageandMaskPanel.Title = 'Substract, Average and Mask';
            app.pre_range_label = uilabel(app.SubstractAverageandMaskPanel);
            app.pre_range_label.Text = 'Pre range';
            app.threshold_run = uibutton(app.SubstractAverageandMaskPanel, 'push');
            app.threshold_run.ButtonPushedFcn = createCallbackFcn(app, @threshold_runButtonPushed, true);
            app.threshold_run.Text = 'RUN';
            app.Contrastthreshold_label = uilabel(app.SubstractAverageandMaskPanel);
            app.Contrastthreshold_label.Text = 'signal threshold';
            app.contrast_thresh = uieditfield(app.SubstractAverageandMaskPanel, 'numeric');
            app.contrast_thresh.Limits = [0 40];
            app.contrast_thresh.ValueDisplayFormat = '%.0f';
            app.contrast_thresh.Value = 10;
            app.prerange_start = uieditfield(app.SubstractAverageandMaskPanel, 'numeric');
            app.prerange_start.Limits = [0 100];
            app.prerange_start.ValueDisplayFormat = '%.6f';
            app.prerange_start.Value = 11;
            app.MTRdisp_val = uicheckbox(app.SubstractAverageandMaskPanel);
            app.MTRdisp_val.Text = 'MTR';
            app.postrange_end = uieditfield(app.SubstractAverageandMaskPanel, 'numeric');
            app.postrange_end.Limits = [0 100];
            app.postrange_end.ValueDisplayFormat = '%.6f';
            app.postrange_end.Value = 40;
            app.MzM0disp_val = uicheckbox(app.SubstractAverageandMaskPanel);
            app.MzM0disp_val.Text = 'Mz/M0';
            app.CESThistogramdisp_val = uicheckbox(app.SubstractAverageandMaskPanel);
            app.CESThistogramdisp_val.Text = 'Signal Histrogram';
            app.pre_range_label_2 = uilabel(app.SubstractAverageandMaskPanel);
            app.pre_range_label_2.Text = 'Display: ';
            app.prerange_end = uieditfield(app.SubstractAverageandMaskPanel, 'numeric');
            app.prerange_end.Limits = [0 100];
            app.prerange_end.ValueDisplayFormat = '%.6f';
            app.prerange_end.Value = 20;
            app.toLabel = uilabel(app.SubstractAverageandMaskPanel);
            app.toLabel.Text = 'to';
            app.toLabel_2 = uilabel(app.SubstractAverageandMaskPanel);
            app.toLabel_2.Text = 'to';
            app.postrange_start = uieditfield(app.SubstractAverageandMaskPanel, 'numeric');
            app.postrange_start.Limits = [0 100];
            app.postrange_start.ValueDisplayFormat = '%.6f';
            app.postrange_start.Value = 27;
            app.Post_range_Label = uilabel(app.SubstractAverageandMaskPanel);
            app.Post_range_Label.Text = 'Post range';
            app.sigenhanc_val = uicheckbox(app.SubstractAverageandMaskPanel);
            app.sigenhanc_val.Text = 'Signal Enhancement';
            app.AverageCESTSignalButtonGroup = uibuttongroup(app.SubstractAverageandMaskPanel);
            app.AverageCESTSignalButtonGroup.Title = 'Average CEST Signal';
            app.average_val = uiradiobutton(app.AverageCESTSignalButtonGroup);
            app.average_val.Text = 'Average';
            app.average_val.Value = true;
            app.minrange_val = uiradiobutton(app.AverageCESTSignalButtonGroup);
            app.minrange_val.Text = 'Min + Range+/-';
            app.offsetsEditFieldLabel = uilabel(app.AverageCESTSignalButtonGroup);
            app.offsetsEditFieldLabel.Text = 'offset(s)';
            app.minrange = uieditfield(app.AverageCESTSignalButtonGroup, 'text');
            app.minrange.Value = '1';
            app.Mzdisp_val = uicheckbox(app.SubstractAverageandMaskPanel);
            app.Mzdisp_val.Text = 'Mz';
            app.DefineROIsPanel = uipanel(app.UIFigure);
            app.DefineROIsPanel.Title = 'Define ROIs ';
            app.loadROIs_pushbutton = uibutton(app.DefineROIsPanel, 'push');
            app.loadROIs_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @loadROIs_pushbuttonPushed, true);
            app.loadROIs_pushbutton.Text = 'LOAD ROIs';
            app.LoanPeriodYearsEditFieldLabel = uilabel(app.DefineROIsPanel);
            app.LoanPeriodYearsEditFieldLabel.Text = '# of ROIs';
            app.roisno_edit = uieditfield(app.DefineROIsPanel, 'numeric');
            app.roisno_edit.Limits = [0 40];
            app.roisno_edit.ValueDisplayFormat = '%.0f';
            app.roisno_edit.Value = 1;
            app.roi_pushbutton = uibutton(app.DefineROIsPanel, 'push');
            app.roi_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @roi_pushbuttonButtonPushed, true);
            app.roi_pushbutton.Text = 'RUN ROIs';
            app.movingAveragesPanel = uipanel(app.UIFigure);
            app.movingAveragesPanel.Title = 'moving Averages';
            app.LoanPeriodYearsEditFieldLabel_3 = uilabel(app.movingAveragesPanel);
            app.LoanPeriodYearsEditFieldLabel_3.Text = 'Pre span';
            app.pre_span = uieditfield(app.movingAveragesPanel, 'numeric');
            app.pre_span.Limits = [0 40];
            app.pre_span.ValueDisplayFormat = '%.0f';
            app.pre_span.Value = 1;
            app.ApplyFilterButton = uibutton(app.movingAveragesPanel, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.LoanPeriodYearsEditFieldLabel_4 = uilabel(app.movingAveragesPanel);
            app.LoanPeriodYearsEditFieldLabel_4.Text = 'Post Span';
            app.post_span = uieditfield(app.movingAveragesPanel, 'numeric');
            app.post_span.Limits = [0 40];
            app.post_span.ValueDisplayFormat = '%.0f';
            app.post_span.Value = 2;
            app.RsquaredthresholdingPanel = uipanel(app.UIFigure);
            app.RsquaredthresholdingPanel.Title = 'R squared thresholding';
            app.LoanPeriodYearsEditFieldLabel_2 = uilabel(app.RsquaredthresholdingPanel);
            app.LoanPeriodYearsEditFieldLabel_2.Text = 'R^2 Threshold';
            app.Heq_threshold = uieditfield(app.RsquaredthresholdingPanel, 'numeric');
            app.Heq_threshold.Limits = [0 100];
            app.Heq_threshold.ValueDisplayFormat = '%.3f';
            app.Heq_threshold.Value = 95;
            app.ApplyThresholdButton = uibutton(app.RsquaredthresholdingPanel, 'push');
            app.ApplyThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyThresholdButtonPushed, true);
            app.ApplyThresholdButton.Text = 'Apply Threshold';
            app.ShapesButtonGroup = uibuttongroup(app.UIFigure);
            app.ShapesButtonGroup.Title = 'Shapes';
            app.FreeButton = uiradiobutton(app.ShapesButtonGroup);
            app.FreeButton.Text = 'Free';
            app.FreeButton.Value = true;
            app.PolyButton = uiradiobutton(app.ShapesButtonGroup);
            app.PolyButton.Text = 'Poly';
            app.ElipseButton = uiradiobutton(app.ShapesButtonGroup);
            app.ElipseButton.Text = 'Elipse';
            app.FreeGrowingButton = uiradiobutton(app.ShapesButtonGroup);
            app.FreeGrowingButton.Text = 'Free Growing';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = 'Panel';
            app.CallibrationCurveCoefficientsPanel = uipanel(app.Panel_3);
            app.CallibrationCurveCoefficientsPanel.Title = 'Callibration Curve Coefficients';
            app.p1EditFieldLabel = uilabel(app.CallibrationCurveCoefficientsPanel);
            app.p1EditFieldLabel.Text = 'p1';
            app.p1EditField = uieditfield(app.CallibrationCurveCoefficientsPanel, 'numeric');
            app.p1EditField.Limits = [-14 14];
            app.p1EditField.ValueDisplayFormat = '%.6f';
            app.p1EditField.Value = -0.0237;
            app.p3EditFieldLabel = uilabel(app.CallibrationCurveCoefficientsPanel);
            app.p3EditFieldLabel.Text = 'p3';
            app.p3EditField = uieditfield(app.CallibrationCurveCoefficientsPanel, 'numeric');
            app.p3EditField.Limits = [-14 14];
            app.p3EditField.ValueDisplayFormat = '%.6f';
            app.p3EditField.Value = -1.029;
            app.p2EditFieldLabel = uilabel(app.CallibrationCurveCoefficientsPanel);
            app.p2EditFieldLabel.Text = 'p2';
            app.p2EditField = uieditfield(app.CallibrationCurveCoefficientsPanel, 'numeric');
            app.p2EditField.Limits = [-14 14];
            app.p2EditField.ValueDisplayFormat = '%.6f';
            app.p2EditField.Value = 0.2509;
            app.p4EditFieldLabel = uilabel(app.CallibrationCurveCoefficientsPanel);
            app.p4EditFieldLabel.Text = 'p4';
            app.p4EditField = uieditfield(app.CallibrationCurveCoefficientsPanel, 'numeric');
            app.p4EditField.Limits = [-14 14];
            app.p4EditField.ValueDisplayFormat = '%.6f';
            app.p4EditField.Value = 7.508;
            app.MTRRatioLabel = uilabel(app.CallibrationCurveCoefficientsPanel);
            app.MTRRatioLabel.Text = 'MTR Ratio';
            app.threshold_run_2 = uibutton(app.Panel_3, 'push');
            app.threshold_run_2.ButtonPushedFcn = createCallbackFcn(app, @threshold_run_2ButtonPushed, true);
            app.threshold_run_2.Text = 'RUN';
            app.ButtonGroup_2 = uibuttongroup(app.Panel_3);
            app.ButtonGroup_2.Title = 'Button Group';
            app.MTRratioButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.MTRratioButton_2.Text = 'MTR ratio';
            app.MTRratioButton_2.Value = true;
            app.pHmapButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.pHmapButton_2.Text = 'pH map';
            app.pHHistogramButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.pHHistogramButton_2.Text = 'pH Histogram';
            app.InverseCheckBox = uicheckbox(app.Panel_3);
            app.InverseCheckBox.Text = 'Inverse';
            app.Panel_2 = uipanel(app.UIFigure);
            app.NormalizeCheckBox = uicheckbox(app.Panel_2);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.PixeloverlayCheckBox = uicheckbox(app.Panel_2);
            app.PixeloverlayCheckBox.Text = 'Pixel overlay';
            app.M0onCheckBox = uicheckbox(app.Panel_2);
            app.M0onCheckBox.Text = 'M0 on';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mcmahonappv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end