classdef Mortgage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure     matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ANALYZELabel                   matlab.ui.control.Label
        FolderPathEditField            matlab.ui.control.NumericEditField
        FolderPathEditFieldLabel       matlab.ui.control.Label
        MonthlyPaymentEditField        matlab.ui.control.NumericEditField
        MonthlyPaymentButton           matlab.ui.control.Button
        LoanPeriodYearsEditField       matlab.ui.control.NumericEditField
        LoanPeriodYearsEditFieldLabel  matlab.ui.control.Label
        InterestRateEditField          matlab.ui.control.NumericEditField
        InterestRateEditFieldLabel     matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        OpenButton                     matlab.ui.control.Button
        FolderPathEditField_2          matlab.ui.control.EditField
        FolderPathEditField_2Label     matlab.ui.control.Label
        CloseButton                    matlab.ui.control.Button
        LOADButton                     matlab.ui.control.Button
        ClearAllButton                 matlab.ui.control.Button
        SAVEButton                     matlab.ui.control.Button
        Slider_3                       matlab.ui.control.Slider
        Slider_2Label_2                matlab.ui.control.Label
        SliceNoSlider                  matlab.ui.control.Slider
        SliceNoSliderLabel             matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Panel3                         matlab.ui.container.Panel
        Panel2_2                       matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        MTRRatioLabel                  matlab.ui.control.Label
        inverseButton                  matlab.ui.control.RadioButton
        CallibrationCurveCoefficientsPanel  matlab.ui.container.Panel
        p4EditField                    matlab.ui.control.NumericEditField
        p4EditFieldLabel               matlab.ui.control.Label
        p2EditField                    matlab.ui.control.NumericEditField
        p2EditFieldLabel               matlab.ui.control.Label
        p3EditField                    matlab.ui.control.NumericEditField
        p3EditFieldLabel               matlab.ui.control.Label
        p1EditField                    matlab.ui.control.NumericEditField
        p1EditFieldLabel               matlab.ui.control.Label
        pHmapButton                    matlab.ui.control.RadioButton
        pHHistogramButton              matlab.ui.control.RadioButton
        MTRratioButton                 matlab.ui.control.RadioButton
        Panel                          matlab.ui.container.Panel
        M0onCheckBox                   matlab.ui.control.CheckBox
        PixeloverlayCheckBox           matlab.ui.control.CheckBox
        NormalizeCheckBox              matlab.ui.control.CheckBox
        PrincipalInterestUIAxes        matlab.ui.control.UIAxes
        COSTUMIZEPanel                 matlab.ui.container.Panel
        Panel2                         matlab.ui.container.Panel
        StackDropDown                  matlab.ui.control.DropDown
        StackDropDownLabel             matlab.ui.control.Label
        NormalizationDropDown          matlab.ui.control.DropDown
        NormalizationDropDownLabel     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {590, 590};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: MonthlyPaymentButton
        function MonthlyPaymentButtonPushed(app, event)
                       
            % Calculate the monthly payment
            amount = app.FolderPathEditField.Value;
            rate = app.InterestRateEditField.Value/12/100;
            nper = 12*app.LoanPeriodYearsEditField.Value;
            payment = (amount*rate)/(1-(1+rate)^-nper);
            app.MonthlyPaymentEditField.Value = payment;
            
            % pre allocating and initializing variables
            interest = zeros(1,nper);
            principal = zeros(1,nper);
            balance = zeros (1,nper);
            
            balance(1) = amount;
            
            % Calculate the principal and interest over time
            for i = 1:nper
                interest(i)  = balance(i)*rate;
                principal(i) = payment - interest(i);
                balance(i+1) = balance(i) - principal(i);
            end
            
            % Plot the principal and interest
            plot(app.PrincipalInterestUIAxes,(1:nper)',principal, ...
                (1:nper)',interest);
            legend(app.PrincipalInterestUIAxes,{'Principal','Interest'},'Location','Best')
            xlim(app.PrincipalInterestUIAxes,[0 nper]); 
            ylim(app.PrincipalInterestUIAxes,'auto');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InterestRateEditFieldLabel = uilabel(app.LeftPanel);
            app.InterestRateEditFieldLabel.Text = 'Interest Rate %';
            app.InterestRateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InterestRateEditField.Limits = [0.001 100];
            app.InterestRateEditField.Value = 4;
            app.LoanPeriodYearsEditFieldLabel = uilabel(app.LeftPanel);
            app.LoanPeriodYearsEditFieldLabel.Text = 'Loan Period (Years)';
            app.LoanPeriodYearsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LoanPeriodYearsEditField.Limits = [10 40];
            app.LoanPeriodYearsEditField.ValueDisplayFormat = '%.0f';
            app.LoanPeriodYearsEditField.Value = 30;
            app.MonthlyPaymentButton = uibutton(app.LeftPanel, 'push');
            app.MonthlyPaymentButton.ButtonPushedFcn = createCallbackFcn(app, @MonthlyPaymentButtonPushed, true);
            app.MonthlyPaymentButton.Text = 'Monthly Payment';
            app.MonthlyPaymentEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MonthlyPaymentEditField.ValueDisplayFormat = '%7.2f';
            app.MonthlyPaymentEditField.Editable = 'off';
            app.FolderPathEditFieldLabel = uilabel(app.LeftPanel);
            app.FolderPathEditFieldLabel.Text = 'Folder Path';
            app.FolderPathEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FolderPathEditField.Limits = [0 10000000];
            app.FolderPathEditField.ValueDisplayFormat = '%8.f';
            app.FolderPathEditField.Value = 300000;
            app.ANALYZELabel = uilabel(app.LeftPanel);
            app.ANALYZELabel.Text = '                            ANALYZE';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.SliceNoSliderLabel = uilabel(app.RightPanel);
            app.SliceNoSliderLabel.Text = 'Slice No.';
            app.SliceNoSlider = uislider(app.RightPanel);
            app.SliceNoSlider.Limits = [1 100];
            app.SliceNoSlider.Value = 1;
            app.Slider_2Label_2 = uilabel(app.RightPanel);
            app.Slider_2Label_2.Text = 'Dynamic No.';
            app.Slider_3 = uislider(app.RightPanel);
            app.Slider_3.Limits = [1 100];
            app.Slider_3.Value = 1;
            app.SAVEButton = uibutton(app.RightPanel, 'push');
            app.SAVEButton.Text = 'SAVE';
            app.ClearAllButton = uibutton(app.RightPanel, 'push');
            app.ClearAllButton.Text = 'Clear All';
            app.LOADButton = uibutton(app.RightPanel, 'push');
            app.LOADButton.Text = 'LOAD';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.Text = 'Close';
            app.FolderPathEditField_2Label = uilabel(app.RightPanel);
            app.FolderPathEditField_2Label.Text = 'Folder Path';
            app.FolderPathEditField_2 = uieditfield(app.RightPanel, 'text');
            app.OpenButton = uibutton(app.RightPanel, 'push');
            app.OpenButton.Text = 'Open';
            app.COSTUMIZEPanel = uipanel(app.MortgageCalculatorUIFigure);
            app.COSTUMIZEPanel.Title = '                                            COSTUMIZE';
            app.Panel2 = uipanel(app.COSTUMIZEPanel);
            app.Panel2.Title = 'Location';
            app.NormalizationDropDownLabel = uilabel(app.Panel2);
            app.NormalizationDropDownLabel.Text = 'Normalization';
            app.NormalizationDropDown = uidropdown(app.Panel2);
            app.NormalizationDropDown.Items = {'Pre First M0', 'Pre Last M0', 'Post First M0', 'Post Last M0', 'All'};
            app.NormalizationDropDown.Value = 'All';
            app.StackDropDownLabel = uilabel(app.Panel2);
            app.StackDropDownLabel.Text = 'Stack';
            app.StackDropDown = uidropdown(app.Panel2);
            app.StackDropDown.Items = {'Pre-injection', 'Post-injection', 'Pre-injection MCORR', 'Post-injection MCORR', 'WASSR', 'All'};
            app.StackDropDown.Value = 'All';
            app.Panel = uipanel(app.MortgageCalculatorUIFigure);
            app.Panel.Title = 'Panel';
            app.PrincipalInterestUIAxes = uiaxes(app.Panel);
            xlabel(app.PrincipalInterestUIAxes, 'Dynamic number')
            ylabel(app.PrincipalInterestUIAxes, 'M_z')
            app.NormalizeCheckBox = uicheckbox(app.Panel);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.PixeloverlayCheckBox = uicheckbox(app.Panel);
            app.PixeloverlayCheckBox.Text = 'Pixel overlay';
            app.M0onCheckBox = uicheckbox(app.Panel);
            app.M0onCheckBox.Text = 'M0 on';
            app.Panel2_2 = uipanel(app.MortgageCalculatorUIFigure);
            app.Panel2_2.Title = 'Panel2';
            app.ButtonGroup = uibuttongroup(app.Panel2_2);
            app.ButtonGroup.Title = 'Create pH Maps';
            app.MTRratioButton = uiradiobutton(app.ButtonGroup);
            app.MTRratioButton.Text = 'MTR ratio';
            app.MTRratioButton.Value = true;
            app.pHHistogramButton = uiradiobutton(app.ButtonGroup);
            app.pHHistogramButton.Text = 'pH Histogram';
            app.pHmapButton = uiradiobutton(app.ButtonGroup);
            app.pHmapButton.Text = 'pH map';
            app.CallibrationCurveCoefficientsPanel = uipanel(app.ButtonGroup);
            app.CallibrationCurveCoefficientsPanel.Title = 'Callibration Curve Coefficients';
            app.p1EditFieldLabel = uilabel(app.CallibrationCurveCoefficientsPanel);
            app.p1EditFieldLabel.Text = 'p1';
            app.p1EditField = uieditfield(app.CallibrationCurveCoefficientsPanel, 'numeric');
            app.p1EditField.Limits = [10 40];
            app.p1EditField.ValueDisplayFormat = '%.0f';
            app.p1EditField.Value = 30;
            app.p3EditFieldLabel = uilabel(app.CallibrationCurveCoefficientsPanel);
            app.p3EditFieldLabel.Text = 'p3';
            app.p3EditField = uieditfield(app.CallibrationCurveCoefficientsPanel, 'numeric');
            app.p3EditField.Limits = [10 40];
            app.p3EditField.ValueDisplayFormat = '%.0f';
            app.p3EditField.Value = 30;
            app.p2EditFieldLabel = uilabel(app.CallibrationCurveCoefficientsPanel);
            app.p2EditFieldLabel.Text = 'p2';
            app.p2EditField = uieditfield(app.CallibrationCurveCoefficientsPanel, 'numeric');
            app.p2EditField.Limits = [10 40];
            app.p2EditField.ValueDisplayFormat = '%.0f';
            app.p2EditField.Value = 30;
            app.p4EditFieldLabel = uilabel(app.CallibrationCurveCoefficientsPanel);
            app.p4EditFieldLabel.Text = 'p4';
            app.p4EditField = uieditfield(app.CallibrationCurveCoefficientsPanel, 'numeric');
            app.p4EditField.Limits = [10 40];
            app.p4EditField.ValueDisplayFormat = '%.0f';
            app.p4EditField.Value = 30;
            app.inverseButton = uiradiobutton(app.ButtonGroup);
            app.inverseButton.Text = 'inverse';
            app.MTRRatioLabel = uilabel(app.ButtonGroup);
            app.MTRRatioLabel.Text = 'MTR Ratio';
            app.Panel3 = uipanel(app.MortgageCalculatorUIFigure);
            app.Panel3.Title = 'Panel3';
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mortgage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end